classdef P2ROUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        P2ROUD_UIFigure            matlab.ui.Figure
        MessagesTabGroup           matlab.ui.container.TabGroup
        MessagesTab                matlab.ui.container.Tab
        statusLabel                matlab.ui.control.Label
        StatusImage                matlab.ui.control.Image
        StatusBox                  matlab.ui.control.TextArea
        MessageBox                 matlab.ui.control.TextArea
        ReconstructionTabGroup     matlab.ui.container.TabGroup
        ReconstructionTab          matlab.ui.container.Tab
        OrientationSpinner         matlab.ui.control.Spinner
        OrientationSpinnerLabel_4  matlab.ui.control.Label
        MovieLabel                 matlab.ui.control.Label
        MovieImage                 matlab.ui.control.Image
        AutoLabel                  matlab.ui.control.Label
        AutoIntensityCheckBox      matlab.ui.control.CheckBox
        Dim6Label                  matlab.ui.control.Label
        Dim5Label                  matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        SetButton                  matlab.ui.control.Button
        WindowEditField            matlab.ui.control.NumericEditField
        LevelEditField             matlab.ui.control.NumericEditField
        WindowSlider               matlab.ui.control.Slider
        LLabel                     matlab.ui.control.Label
        WLabel                     matlab.ui.control.Label
        LevelSlider                matlab.ui.control.Slider
        Dim4Label                  matlab.ui.control.Label
        Dim3Label                  matlab.ui.control.Label
        Dim6Slider                 matlab.ui.control.Slider
        Dim6EditField              matlab.ui.control.NumericEditField
        XLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        Dim4EditField              matlab.ui.control.NumericEditField
        Dim5EditField              matlab.ui.control.NumericEditField
        Dim3EditField              matlab.ui.control.NumericEditField
        Dim4Slider                 matlab.ui.control.Slider
        Dim5Slider                 matlab.ui.control.Slider
        Dim3Slider                 matlab.ui.control.Slider
        RecoFig                    matlab.ui.control.UIAxes
        ScanParameterTabGroup      matlab.ui.container.TabGroup
        ScanParametersTab          matlab.ui.container.Tab
        TimeDynViewField           matlab.ui.control.EditField
        TimedynEditFieldLabel      matlab.ui.control.Label
        ScanTimeViewField          matlab.ui.control.EditField
        ScantimeEditFieldLabel     matlab.ui.control.Label
        Trajectory                 matlab.ui.control.EditField
        TrajectoryLabel            matlab.ui.control.Label
        FAViewField                matlab.ui.control.EditField
        FAdegEditFieldLabel        matlab.ui.control.Label
        MRDfileViewField           matlab.ui.control.EditField
        MRDfileLabel               matlab.ui.control.Label
        NEViewField                matlab.ui.control.NumericEditField
        NEEditFieldLabel           matlab.ui.control.Label
        NFAViewField               matlab.ui.control.NumericEditField
        NFALabel                   matlab.ui.control.Label
        NAViewField                matlab.ui.control.NumericEditField
        NALabel                    matlab.ui.control.Label
        TEViewField                matlab.ui.control.NumericEditField
        TEmsLabel                  matlab.ui.control.Label
        TRViewField                matlab.ui.control.NumericEditField
        TRmsLabel                  matlab.ui.control.Label
        FOVViewField1              matlab.ui.control.NumericEditField
        FOVmmLabel                 matlab.ui.control.Label
        KMatrixViewField1          matlab.ui.control.NumericEditField
        MatrixEditFieldLabel       matlab.ui.control.Label
        SequenceViewField          matlab.ui.control.EditField
        SequenceEditFieldLabel     matlab.ui.control.Label
        NRViewField                matlab.ui.control.NumericEditField
        NREditFieldLabel_3         matlab.ui.control.Label
        MZLabel                    matlab.ui.control.Label
        MYLabel                    matlab.ui.control.Label
        MXLabel                    matlab.ui.control.Label
        FOVViewField3              matlab.ui.control.NumericEditField
        FOVViewField2              matlab.ui.control.NumericEditField
        KMatrixViewField3          matlab.ui.control.NumericEditField
        KMatrixViewField2          matlab.ui.control.NumericEditField
        KspaceTabGroup             matlab.ui.container.TabGroup
        KspaceTab                  matlab.ui.container.Tab
        CoilSpinner                matlab.ui.control.Spinner
        CoilSpinnerLabel           matlab.ui.control.Label
        KFlipAngleEditField        matlab.ui.control.NumericEditField
        FALabel                    matlab.ui.control.Label
        KOrientationSpinner        matlab.ui.control.Spinner
        OrientationSpinnerLabel_3  matlab.ui.control.Label
        KEchoEditField             matlab.ui.control.NumericEditField
        EchoLabel                  matlab.ui.control.Label
        KWindowEditField           matlab.ui.control.NumericEditField
        KLevelEditField            matlab.ui.control.NumericEditField
        KWindowSlider              matlab.ui.control.Slider
        KLLabel                    matlab.ui.control.Label
        KWLabel                    matlab.ui.control.Label
        KLevelSlider               matlab.ui.control.Slider
        FastSlowSwitch             matlab.ui.control.RockerSwitch
        KspacemovieLabel           matlab.ui.control.Label
        KStopButton                matlab.ui.control.Button
        KStartButton               matlab.ui.control.Button
        FillingPercLabel           matlab.ui.control.Label
        FillingViewField           matlab.ui.control.NumericEditField
        KFlipAngleSlider           matlab.ui.control.Slider
        KDim4Label                 matlab.ui.control.Label
        KEchoSlider                matlab.ui.control.Slider
        KDim3Label                 matlab.ui.control.Label
        ButtonGroup                matlab.ui.container.ButtonGroup
        kvaluesButton              matlab.ui.control.RadioButton
        averagesButton             matlab.ui.control.RadioButton
        fillingButton              matlab.ui.control.RadioButton
        KyLabel                    matlab.ui.control.Label
        KxLabel                    matlab.ui.control.Label
        KDim4EditField             matlab.ui.control.NumericEditField
        KDim4Slider                matlab.ui.control.Slider
        KDim3Slider                matlab.ui.control.Slider
        KDim3EditField             matlab.ui.control.NumericEditField
        KspaceFig                  matlab.ui.control.UIAxes
        CoilsTab                   matlab.ui.container.Tab
        CoilselectionPanel         matlab.ui.container.Panel
        AutoSensitivityCheckBox    matlab.ui.control.CheckBox
        S8EditField                matlab.ui.control.NumericEditField
        S7EditField                matlab.ui.control.NumericEditField
        S6EditField                matlab.ui.control.NumericEditField
        S5EditField                matlab.ui.control.NumericEditField
        S4EditField                matlab.ui.control.NumericEditField
        S3EditField                matlab.ui.control.NumericEditField
        RelativesensitivityLabel   matlab.ui.control.Label
        S2EditField                matlab.ui.control.NumericEditField
        CoilLabel                  matlab.ui.control.Label
        S1EditField                matlab.ui.control.NumericEditField
        C8CheckBox                 matlab.ui.control.CheckBox
        C7CheckBox                 matlab.ui.control.CheckBox
        C6CheckBox                 matlab.ui.control.CheckBox
        C5CheckBox                 matlab.ui.control.CheckBox
        C4CheckBox                 matlab.ui.control.CheckBox
        C3CheckBox                 matlab.ui.control.CheckBox
        C2CheckBox                 matlab.ui.control.CheckBox
        C1CheckBox                 matlab.ui.control.CheckBox
        DashboardTabGroup          matlab.ui.container.TabGroup
        DashboardTab               matlab.ui.container.Tab
        Lamp6                      matlab.ui.control.Lamp
        ExportGIFButton            matlab.ui.control.Button
        Lamp5                      matlab.ui.control.Lamp
        ExportMRDButton            matlab.ui.control.Button
        Lamp4                      matlab.ui.control.Lamp
        ExportNIFTIButton          matlab.ui.control.Button
        ExportProgressGauge        matlab.ui.control.LinearGauge
        RecoProgressGauge          matlab.ui.control.LinearGauge
        AbortButton                matlab.ui.control.Button
        RecoregularizationPanel    matlab.ui.container.Panel
        LRxyzEditField             matlab.ui.control.NumericEditField
        LRxyzEditFieldLabel        matlab.ui.control.Label
        CSRecoLabel                matlab.ui.control.Label
        CSRecoCheckBox             matlab.ui.control.CheckBox
        WVxyzEditField             matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel        matlab.ui.control.Label
        TVtimeEditField            matlab.ui.control.NumericEditField
        TVtimeEditFieldLabel       matlab.ui.control.Label
        TVxyzEditField             matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel        matlab.ui.control.Label
        RecodimensionsPanel        matlab.ui.container.Panel
        NREditField                matlab.ui.control.NumericEditField
        NREditFieldLabel_2         matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        ExitButton                 matlab.ui.control.Button
        Lamp3                      matlab.ui.control.Lamp
        ExportDCMButton            matlab.ui.control.Button
        Lamp2                      matlab.ui.control.Lamp
        ReconstructButton          matlab.ui.control.Button
        Lamp1                      matlab.ui.control.Lamp
        LoadMRDButton              matlab.ui.control.Button
        P2ROUDLabel                matlab.ui.control.Label
        MRSImage                   matlab.ui.control.Image
        CreditsLabel               matlab.ui.control.Label
        TulpImage                  matlab.ui.control.Image
        TitleLabel                 matlab.ui.control.Label
    end


    % ------------------------------------------------------
    %
    % P2ROUD
    % Preclinical PROspective Undersampling in multiple Dimensions
    %
    % Bram Coolen, Gustav Strijkers
    % Amsterdam UMC
    % b.f.coolen@amsterdamumc.nl
    % g.j.strijkers@amsterdamumc.nl
    %
    %
    % Version 1.0
    % - initial commit
    %
    %
    % Version 1.1
    % - add scaling for k-space visualization
    % - corrected orientation of odd-echo images in multi-gradient-echo
    %
    %
    % Version 1.2
    % - bug fixes
    % - reconstruction of CINE images, for which NE-dimension is the CINE loop
    % - GIF export
    % - Image zoom
    % - NIFTI export for 2D multislice data 
    % - Bug fix in DICOM export 
    % - Bug fix in NIFTI export dimensions
    %
    %
    % Version 1.3
    % - multi coil reconstruction panel
    % - bug fixes
    % - read total acquisition time from rpr file if available
    %
    % Version 1.4
    % - Low-rank in spatial domain reconstruction added (with BART)
    % - Improved Tukey filter centered around k-space maximum
    % - rtable.rtv is renamed to origrtable.rtv.orig to prevent resorting of kspace with sur export
    % - removed CINE flipping of odd images, for some reason not needed anymore
    % - Added reconstruction parameter export file
    %


    properties (Access = private)
        
        % App directories
        appdir                  % Application directory
        olddir                  % Starting directory
        bart_detected           % Bart toolbox detected true or false
        
        % File parameters
        mrd_import_path         % MRD import path
        mrd_export_path         % MRD export path
        dicom_export_path       % Dicom export path
        nifti_export_path       % NIFTI export path
        gif_export_path         % GIF export path
        rpr                     % rpr information
        rpr_flag                % rpr information available true or false
        tag                     % filename tag
        valid_file              % Valid data file true or false
        mrd_flag                % MRD directory checked true or false
        dicom_flag              % Dicom directory checked true or false
        proudrecoscan           % Flag whether this is data previously reconstructed with the same app
        data_type               % Datatype 2D, 3D, etc..
        traj_type               % Trajectory type (scanner, mul_kspace, rvtable, p2roud)
        data_file               % Data file name
        
        % Data property parameters
        multicoil               % Multicoil data true or false
        ncoils                  % Number of coils
        more_echoes             % More than 1 echo true or false
        more_repetitions        % More than 1 repetition true or false
        more_flipangles         % More than 1 flip angle true or false
        flipangles              % Flip angle array
        totalacquisitiontime    % Total acquisition time
        
        % Reconstructed data properties
        valid_reco              % Valid reconstruction true or false
        stop_reco               % Stop the reco true or false
        kspace                  % k-space data
        mrd_kspace              % k-space data for MRD export
        ukspace                 % unsorted k-space data
        images                  % Reconstructed images
        nsa_space               % Nubmer of signal averages per k-point
        filling_space           % K-space points acquired (1) or not (0)
        kmovie_space            % K-space filling for movie
        seqtrajectory           % sequential trajectory list
        validtrajectory         % sequential directory true or false
        reco_matrix             % Reconstructed image size
        validNIFTIdata          % Data suitable for NIFTI export true or false
        valid_movie             % Data with multiple repetitions true or false
        coilsensitivities       % Relative coil sensitivities
        coilactive              % Coil active yes=1/no=1
        
        % Figure & plot elements
        image_orient            % image orientation permutation matrix
        kspace_orient_label     % Orientation labels in k-space figure
        image_orient_label      % Orientation labels in image figure
        cbar                    % kspace averages and filling colorbar
        rect                    % rectangle box
        green                   % other shade of green
        brightgreen             % green
        red                     % other shade of red
        brightred               % red
        blue                    % blue
        yellow                  % yellow
        orange                  % orange
        klevel                  % K-space image intensity level
        kwindow                 % K-space image intensity window
        level                   % Image intensity level
        window                  % Image intensity window
        stopmovie               % Movie running (false) or stopping (true)
        kstopmovie              % K movie running (false) or stopping (true)
        kmoviefig               % K movie fig object
        buttonstatus            % status of the buttons
        newfigflag              % new figure flag false or true
        recoimage               % reconstructed image object
        
    end
    
    properties (Access = public)
        
        seqpar                  % Sequence parameters
        mrdfooter               % mrd footer file
         
    end


    % --------------------------------------------------------------
    %
    %                     PRIVATE FUNCTIONS
    %
    % --------------------------------------------------------------
    
    
    methods (Access = private)
        
        
        % --------------------------------------------------------------
        %
        %                     LIGHTS AND BUTTONS
        %
        % --------------------------------------------------------------
        
        % Set the lights
        function SetLights(app,code)
            
            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            if ~strcmp(code{5},'Null') app.Lamp5.Color = code{5}; end
            if ~strcmp(code{6},'Null') app.Lamp6.Color = code{6}; end
            drawnow;
            
        end
        
        
        % Set the buttons
        function SetButtons(app,code)
            
            if ~strcmp(code{1},'Null') app.LoadMRDButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.ReconstructButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.ExportDCMButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.ExportNIFTIButton.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.ExportMRDButton.Enable = code{5}; end
            if ~strcmp(code{6},'Null') app.ExportGIFButton.Enable = code{6}; end
            if ~strcmp(code{7},'Null') app.ExitButton.Enable = code{7}; end
            drawnow;
            
        end
        
        
        % Switch of all buttons
        function SwitchOffAllButtons(app)
            
            app.SetButtons({'Off','Off','Off','Off','Off','Off','Off'});
            
        end
        
        
        % Enable/disable reco parameters
        function SetRecoFields(app)
            
            if app.CSRecoCheckBox.Value == 1
                app.WVxyzEditField.Enable = 'on';
                app.TVxyzEditField.Enable = 'on';
                app.LRxyzEditField.Enable = 'on';
                app.TVtimeEditField.Enable = 'on';
            else
                app.WVxyzEditField.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.LRxyzEditField.Enable = 'off';
                app.TVtimeEditField.Enable = 'off';
            end
            
            if ~app.bart_detected
                app.TVxyzEditField.Enable = 'off';
                app.LRxyzEditField.Enable = 'off';
            end
            
            drawnow;
            
        end
        
        
        % Disable k-buttons
        function DisableKbuttons(app)
            
            app.buttonstatus = [app.KDim3EditField.Enable;app.KDim3Slider.Enable;app.KDim4EditField.Enable;app.KDim4Slider.Enable; ...
                app.KFlipAngleEditField.Enable;app.KFlipAngleSlider.Enable;app.KEchoEditField.Enable;app.KEchoSlider.Enable; ...
                app.KOrientationSpinner.Enable;app.fillingButton.Enable;app.averagesButton.Enable;app.kvaluesButton.Enable;app.CoilSpinner.Enable];
            
            app.KDim3EditField.Enable = 'off';
            app.KDim3Slider.Enable = 'off';
            app.KDim4EditField.Enable = 'off';
            app.KDim4Slider.Enable = 'off';
            app.KFlipAngleEditField.Enable = 'off';
            app.KFlipAngleSlider.Enable = 'off';
            app.KEchoEditField.Enable = 'off';
            app.KEchoSlider.Enable = 'off';
            app.KOrientationSpinner.Enable = 'off';
            app.fillingButton.Enable = 'off';
            app.averagesButton.Enable = 'off';
            app.kvaluesButton.Enable = 'off';
            app.CoilSpinner.Enable = 'off';
            
            drawnow;
            
        end
               
        
        % Enable k-buttons
        function EnableKbuttons(app)
            
            app.KDim3EditField.Enable = app.buttonstatus(1);
            app.KDim3Slider.Enable = app.buttonstatus(2);
            app.KDim4EditField.Enable = app.buttonstatus(3);
            app.KDim4Slider.Enable = app.buttonstatus(4);
            app.KFlipAngleEditField.Enable = app.buttonstatus(5);
            app.KFlipAngleSlider.Enable = app.buttonstatus(6);
            app.KEchoEditField.Enable = app.buttonstatus(7);
            app.KEchoSlider.Enable = app.buttonstatus(8);
            app.KOrientationSpinner.Enable = app.buttonstatus(9);
            app.fillingButton.Enable = app.buttonstatus(10);
            app.averagesButton.Enable = app.buttonstatus(11);
            app.kvaluesButton.Enable = app.buttonstatus(12);
            app.CoilSpinner.Enable = app.buttonstatus(13);
            
            drawnow;
            
        end
                
         
        % Reset coil buttons
        function ResetCoilButtons(app)
            
            app.C1CheckBox.Value = 1;
            app.C1CheckBox.Enable = 'on';
            if app.ncoils > 1 app.C2CheckBox.Enable = 'on'; app.C2CheckBox.Value = 1; else app.C2CheckBox.Enable = 'off'; app.C2CheckBox.Value = 0; end
            if app.ncoils > 2 app.C3CheckBox.Enable = 'on'; app.C3CheckBox.Value = 1; else app.C3CheckBox.Enable = 'off'; app.C3CheckBox.Value = 0; end
            if app.ncoils > 3 app.C4CheckBox.Enable = 'on'; app.C4CheckBox.Value = 1; else app.C4CheckBox.Enable = 'off'; app.C4CheckBox.Value = 0; end
            if app.ncoils > 4 app.C5CheckBox.Enable = 'on'; app.C5CheckBox.Value = 1; else app.C5CheckBox.Enable = 'off'; app.C5CheckBox.Value = 0; end
            if app.ncoils > 5 app.C6CheckBox.Enable = 'on'; app.C6CheckBox.Value = 1; else app.C6CheckBox.Enable = 'off'; app.C6CheckBox.Value = 0; end
            if app.ncoils > 6 app.C7CheckBox.Enable = 'on'; app.C7CheckBox.Value = 1; else app.C7CheckBox.Enable = 'off'; app.C7CheckBox.Value = 0; end
            if app.ncoils > 7 app.C8CheckBox.Enable = 'on'; app.C8CheckBox.Value = 1; else app.C8CheckBox.Enable = 'off'; app.C8CheckBox.Value = 0; end
            
            app.S1EditField.Value = 1;
            app.S2EditField.Value = 1;
            app.S3EditField.Value = 1;
            app.S4EditField.Value = 1;
            app.S5EditField.Value = 1;
            app.S6EditField.Value = 1;
            app.S7EditField.Value = 1;
            app.S8EditField.Value = 1;
            
            app.coilsensitivities = [app.S1EditField.Value app.S2EditField.Value app.S3EditField.Value app.S4EditField.Value ...
                app.S5EditField.Value app.S6EditField.Value app.S7EditField.Value app.S8EditField.Value];
            
            app.coilactive = [app.C1CheckBox.Value app.C2CheckBox.Value app.C3CheckBox.Value app.C4CheckBox.Value ...
                app.C5CheckBox.Value app.C6CheckBox.Value app.C7CheckBox.Value app.C8CheckBox.Value];
            
            app.S1EditField.Enable = 'on';
            if app.ncoils > 1 app.S2EditField.Enable = 'on'; else app.S2EditField.Enable = 'off'; end
            if app.ncoils > 2 app.S3EditField.Enable = 'on'; else app.S3EditField.Enable = 'off'; end
            if app.ncoils > 3 app.S4EditField.Enable = 'on'; else app.S4EditField.Enable = 'off'; end
            if app.ncoils > 4 app.S5EditField.Enable = 'on'; else app.S5EditField.Enable = 'off'; end
            if app.ncoils > 5 app.S6EditField.Enable = 'on'; else app.S6EditField.Enable = 'off'; end
            if app.ncoils > 6 app.S7EditField.Enable = 'on'; else app.S7EditField.Enable = 'off'; end
            if app.ncoils > 7 app.S8EditField.Enable = 'on'; else app.S8EditField.Enable = 'off'; end
            
            app.AutoSensitivityCheckBox.Value = 0;
            app.AutoSensitivityCheckBox.Enable = 'on';
            
            drawnow
            
        end
        
        
        % Enable coil buttons
        function EnableCoilButtons(app)
            
            app.C1CheckBox.Enable = 'on';
            if app.ncoils > 1 app.C2CheckBox.Enable = 'on'; else app.C2CheckBox.Enable = 'off'; end
            if app.ncoils > 2 app.C3CheckBox.Enable = 'on'; else app.C3CheckBox.Enable = 'off'; end
            if app.ncoils > 3 app.C4CheckBox.Enable = 'on'; else app.C4CheckBox.Enable = 'off'; end
            if app.ncoils > 4 app.C5CheckBox.Enable = 'on'; else app.C5CheckBox.Enable = 'off'; end
            if app.ncoils > 5 app.C6CheckBox.Enable = 'on'; else app.C6CheckBox.Enable = 'off'; end
            if app.ncoils > 6 app.C7CheckBox.Enable = 'on'; else app.C7CheckBox.Enable = 'off'; end
            if app.ncoils > 7 app.C8CheckBox.Enable = 'on'; else app.C8CheckBox.Enable = 'off'; end
            
            app.AutoSensitivityCheckBox.Enable = 'on';
            if app.AutoSensitivityCheckBox.Value == 0
                
                app.S1EditField.Enable = 'on';
                if app.ncoils > 1 app.S2EditField.Enable = 'on'; else app.S2EditField.Enable = 'off'; end
                if app.ncoils > 2 app.S3EditField.Enable = 'on'; else app.S3EditField.Enable = 'off'; end
                if app.ncoils > 3 app.S4EditField.Enable = 'on'; else app.S4EditField.Enable = 'off'; end
                if app.ncoils > 4 app.S5EditField.Enable = 'on'; else app.S5EditField.Enable = 'off'; end
                if app.ncoils > 5 app.S6EditField.Enable = 'on'; else app.S6EditField.Enable = 'off'; end
                if app.ncoils > 6 app.S7EditField.Enable = 'on'; else app.S7EditField.Enable = 'off'; end
                if app.ncoils > 7 app.S8EditField.Enable = 'on'; else app.S8EditField.Enable = 'off'; end
                
            else
                
                app.S1EditField.Enable = 'off';
                app.S2EditField.Enable = 'off';
                app.S3EditField.Enable = 'off';
                app.S4EditField.Enable = 'off';
                app.S5EditField.Enable = 'off';
                app.S6EditField.Enable = 'off';
                app.S7EditField.Enable = 'off';
                app.S8EditField.Enable = 'off';
                
            end
            
            drawnow
            
        end
        
        
        % Disable coil buttons
        function DisableCoilButtons(app)
            
            app.C1CheckBox.Enable = 'off';
            app.C2CheckBox.Enable = 'off';
            app.C3CheckBox.Enable = 'off';
            app.C4CheckBox.Enable = 'off';
            app.C5CheckBox.Enable = 'off';
            app.C6CheckBox.Enable = 'off';
            app.C7CheckBox.Enable = 'off';
            app.C8CheckBox.Enable = 'off';
            
            app.AutoSensitivityCheckBox.Enable = 'off';
            
            app.S1EditField.Enable = 'off';
            app.S2EditField.Enable = 'off';
            app.S3EditField.Enable = 'off';
            app.S4EditField.Enable = 'off';
            app.S5EditField.Enable = 'off';
            app.S6EditField.Enable = 'off';
            app.S7EditField.Enable = 'off';
            app.S8EditField.Enable = 'off';
            
            drawnow
            
        end


        % Set images, figure properties, etc..
        function SetAppLayout(app)
            
            % Images
            app.TulpImage.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';
            app.MovieImage.ImageSource = 'Movie.gif';
            
            % Buttons
            app.LoadMRDButton.Icon = 'disk.png';
            app.ReconstructButton.Icon = 'reconstruct.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportNIFTIButton.Icon = 'disk.png';
            app.ExportMRDButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StartButton.Icon = 'play.gif';
            app.StopButton.Icon = 'stop.gif';
            app.KStartButton.Icon = 'play.gif';
            app.KStopButton.Icon = 'stop.gif';
            
            % Title
            app.TitleLabel.Text = ['P' char(178) 'ROUD'];
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            
            % Block figure interactivity
            app.KspaceFig.Toolbar.Visible = 'off';
            app.KspaceFig.Interactions = [];
            app.RecoFig.Toolbar.Visible = 'off';
            app.RecoFig.Interactions = [zoomInteraction regionZoomInteraction];
            
        end
        
        
        % --------------------------------------------------------------
        %
        %                           PARAMETERS
        %
        % --------------------------------------------------------------
        
        
        % Set data parameters
        function SetParametersFcn(app)
            
            app.TextMessage('Setting k-space parameters ....');
            app.Trajectory.Value = '  -';
            
            % 2D or 3D data
            app.ZEditField.Enable = 'off';
            app.data_type = "2D";
            if isfield(app.seqpar,'NO_VIEWS_2')
                if app.seqpar.NO_VIEWS_2 > 1
                    app.TextMessage('3D data ...');
                    app.data_type = "3D";
                    app.ZEditField.Enable = 'on';
                end
            end
            
            % check for radial data acquisition
            if isfield(app.seqpar,'radial_on')
                if app.seqpar.radial_on == 1
                    app.data_type = "2Dradial";
                    % center the echo for radial acquistions
                    % for i = 1:app.ncoils
                    %    app.kspace{i} = center_radial_echo(app,app.kspace{i});
                    % end
                end
            end
            
            % More than 1 flip angle
            app.flipangles = app.seqpar.alpha;
            if isfield(app.seqpar, 'VFA_size')
                if app.seqpar.VFA_size > 0
                    app.TextMessage('Variable flip-angle data detected ...');
                    app.more_flipangles = true;
                    app.flipangles = app.seqpar.VFA_angles(1:app.seqpar.VFA_size);
                end
            end
            app.seqpar.flipanglearray = app.flipangles;
            
            % More than 1 echo
            if isfield(app.seqpar, 'NO_ECHOES')
                if app.seqpar.NO_ECHOES > 1
                    app.TextMessage('Multi-echo data detected ...');
                    app.more_echoes = true;
                end
            else
                app.seqpar.NO_ECHOES = 1;
            end
            
            % More than 1 repetition
            if isfield(app.seqpar,"EXPERIMENT_ARRAY")
                if app.seqpar.EXPERIMENT_ARRAY > 1
                    if app.more_flipangles
                        app.seqpar.EXPERIMENT_ARRAY = app.seqpar.EXPERIMENT_ARRAY/app.seqpar.VFA_size;
                    end
                    if app.seqpar.EXPERIMENT_ARRAY > 1
                        app.TextMessage('Multiple repetitions detected ...');
                        app.more_repetitions = true;
                    end
                end
            else
                app.seqpar.EXPERIMENT_ARRAY = 1;
            end
            
            % Check for scanner k-space table (RARE sequence)
            app.traj_type = "";
            if ((exist(strcat(app.mrd_import_path,filesep,'rtable.rtv'), 'file') == 2) || (exist(strcat(app.mrd_import_path,filesep,'origrtable.rtv.orig'), 'file') == 2)) ... 
                    && (app.proudrecoscan == false) && (app.data_type ~= "2Dradial")
                app.TextMessage('Scanner k-space table detected ...');
                app.traj_type = "rtable";
                app.Trajectory.Value = 'rtable.rtv';
            end
            
            % User k-space trajectory & P2ROUD implementation
            if isfield(app.seqpar, 'pe1_order') && (app.proudrecoscan == false)
                
                % pe1_order == 3 : k-space trajectory list in preclinical
                if (app.seqpar.pe1_order == 3) && (app.data_type ~= "2Dradial")
                    app.traj_type = "mul_kspace";
                    app.Trajectory.Value = 'mul_kspace_traj';
                end
                
                % pe1_order == 4 : 3D k-space trajectory from txt file
                if (app.seqpar.pe1_order == 4) && (app.data_type ~= "2Dradial")
                    app.TextMessage('P2ROUD k-space trajectory ...');
                    % Read k-space trajectory file
                    flist = dir(fullfile(app.mrd_import_path,'*.txt'));
                    if ~isempty(flist)
                        app.seqpar.gp_var_proud = load([flist(1).folder,filesep,flist(1).name]);
                        app.Trajectory.Value = flist(1).name;
                        app.traj_type = "P2ROUD";
                        app.TextMessage(strcat('P2ROUD trajectory file = ',{' '},flist(1).name));
                    else
                        app.traj_type = "";
                        app.TextMessage('ERROR: Trajectory file not found ...');
                        app.SetStatus(2);
                    end
                end
                
            end
            
            % Show some parameters in app
            app.SequenceViewField.Value = app.seqpar.PPL;
            app.TRViewField.Value = app.seqpar.tr;
            app.TEViewField.Value = app.seqpar.te;
            app.NAViewField.Value = app.seqpar.NO_AVERAGES;
            app.NRViewField.Value = app.seqpar.EXPERIMENT_ARRAY;
            app.NEViewField.Value = app.seqpar.NO_ECHOES;
            app.FAViewField.Value = ['  ',num2str(app.flipangles)];
            app.NFAViewField.Value = length(app.flipangles);
            app.seqpar.aratio = app.seqpar.FOVf/8;
            
            % Matrix size
            switch app.data_type
                
                case "3D"
                    
                    % 3D data
                    app.MZLabel.Text = 'kz';
                    app.ZEditFieldLabel.Text = 'Z';
                    
                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.seqpar.NO_VIEWS_2;
                    
                    % Permute data to (X, Y, Z, other)
                    for i=1:app.ncoils
                        
                        switch ndims(app.kspace{i})
                            
                            case 3
                                app.kspace{i} = permute(app.kspace{i},[3,1,2]);
                                
                            case 4
                                app.kspace{i} = permute(app.kspace{i},[4,2,3,1]);
                                
                            case 5
                                app.kspace{i} = permute(app.kspace{i},[5,3,4,1,2]);
                                
                            case 6
                                app.kspace{i} = permute(app.kspace{i},[6,4,5,1,2,3]);
                                
                        end
                        
                    end
                    
                    % Field of view
                    app.FOVViewField1.Value = app.seqpar.FOV;
                    app.FOVViewField2.Value = app.seqpar.FOV*app.seqpar.aratio;
                    app.FOVViewField3.Value = app.seqpar.SLICE_THICKNESS;
                
                    
                case {"2D","2Dradial"}
                    
                    
                    % 2D data or radial data
                    app.MZLabel.Text = 'Slices';
                    app.ZEditFieldLabel.Text = 'Slices';
                    
                    % Matrix dimensions
                    app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                    app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.seqpar.NO_SLICES;
                    
                    % Permute data to (X, Y, Z, other)
                    for i=1:app.ncoils
                        
                        switch ndims(app.kspace{i})
                            
                            case 2
                                app.kspace{i} = permute(app.kspace{i},[2,1]);
                                
                            case 3
                                if app.seqpar.NO_SLICES > 1
                                    app.kspace{i} = permute(app.kspace{i},[3,2,1]);
                                else
                                    app.kspace{i} = permute(app.kspace{i},[3,2,4,1]);
                                end
                                
                            case 4
                                if app.seqpar.NO_SLICES > 1
                                    app.kspace{i} = permute(app.kspace{i},[4,3,2,1]);
                                else
                                    app.kspace{i} = permute(app.kspace{i},[4,3,5,1,2]);
                                end
                                
                            case 5
                                if app.seqpar.NO_SLICES > 1
                                    app.kspace{i} = permute(app.kspace{i},[5,4,3,1,2]);
                                else
                                    app.kspace{i} = permute(app.kspace{i},[5,4,6,1,2,3]);
                                end
                                
                            case 6
                                if app.seqpar.NO_SLICES > 1
                                    app.kspace{i} = permute(app.kspace{i},[6,5,4,1,2,3]);
                                else
                                    app.kspace{i} = permute(app.kspace{i},[6,5,7,1,2,3,4]);
                                end
                                
                        end
                        
                    end
                    
                    % Field of view
                    app.FOVViewField1.Value = app.seqpar.FOV;
                    app.FOVViewField2.Value = app.seqpar.FOV*app.seqpar.aratio;
                    app.FOVViewField3.Value = app.seqpar.SLICE_THICKNESS;
                    
            end
            
            % Permute data to (X, Y, Z, NR, NFA, NE)    ---- NOT FULLY TESTED -----
            for i=1:app.ncoils
                
                if ndims(app.kspace{i})==4 && app.more_flipangles app.kspace{i} = permute(app.kspace{i},[1,2,3,5,4,6]); end
                if ndims(app.kspace{i})==4 && app.more_echoes app.kspace{i} = permute(app.kspace{i},[1,2,3,5,6,4]); end
                if ndims(app.kspace{i})==5 && app.more_echoes && app.more_flipangles app.kspace{i} = permute(app.kspace{i},[1,2,3,6,4,5]); end
                if ndims(app.kspace{i})==5 && app.more_echoes && app.more_repetitions app.kspace{i} = permute(app.kspace{i},[1,2,3,4,6,5]); end
                if ndims(app.kspace{i})==5 && app.more_repetitions && app.more_flipangles app.kspace{i} = permute(app.kspace{i},[1,2,3,4,5,6]); end
                
            end
            
            % Image orientations
            switch app.data_type
                
                case "3D"
                    
                    app.image_orient = ... 
                        [1 2 3 4 5 6 ; 2 3 1 4 5 6 ; 3 1 2 4 5 6 ; ...
                         4 1 2 3 5 6 ; 4 2 3 1 5 6 ; 4 3 1 2 5 6 ; ...
                         1 2 4 3 5 6 ; 2 3 4 1 5 6 ; 3 1 4 2 5 6 ; ...
                         1 2 3 6 4 5 ; 1 3 2 6 4 5 ; 2 3 1 6 4 5 ];
                    
                    app.kspace_orient_label = ["Kx","Ky","Kz","NR","FA","Echo" ; "Ky","Kz","Kx","NR","FA","Echo" ; "Kz","Kx","Ky","NR","FA","Echo" ; ...
                        "NR","Kx","Ky","Kz","FA","Echo" ; "NR","Ky","Kz","Kx","FA","Echo" ; "NR","Kz","Kx","Ky","FA","Echo" ; ...
                        "Kx","Ky","NR","Kz","FA","Echo" ; "Ky","Kz","NR","Kx","FA","Echo" ; "Kz","Kx","NR","Ky","FA","Echo" ; ...
                        "Kx","Ky","Kz","Echo","NR","FA" ; "Kx","Kz","Ky","Echo","NR","FA" ; "Ky","Kz","Kx","Echo","NR","FA" ];
                    
                    app.image_orient_label = ["X","Y","Z","NR","FA","Echo" ; "Y","Z","X","NR","FA","Echo" ; "Z","X","Y","NR","FA","Echo" ; ...
                        "NR","X","Y","Z","FA","Echo" ; "NR","Y","Z","X","FA","Echo" ; "NR","Z","X","Y","FA","Echo" ; ...
                        "X","Y","NR","Z","FA","Echo" ; "Y","Z","NR","X","FA","Echo" ; "Z","X","NR","Y","FA","Echo" ; ...
                        "X","Y","Z","Echo","NR","FA" ; "X","Z","Y","Echo","NR","FA" ; "Y","Z","X","Echo","NR","FA" ];
                    
                case {"2D","2Dradial"}
                    
                    app.image_orient = ... 
                        [1 2 3 4 5 6 ; 2 3 1 4 5 6 ; 3 1 2 4 5 6 ; ...
                         4 1 2 3 5 6 ; 4 2 3 1 5 6 ; 4 3 1 2 5 6 ; ...
                         1 2 4 3 5 6 ; 2 3 4 1 5 6 ; 3 1 4 2 5 6 ; ...
                         1 2 3 6 4 5 ; 1 3 2 6 4 5 ; 2 3 1 6 4 5 ];
                    
                    app.kspace_orient_label = ["Kx","Ky","Slice","NR","FA","Echo" ; "Ky","Slice","Kx","NR","FA","Echo" ; "Slice","Kx","Ky","NR","FA","Echo" ; ...
                        "NR","Kx","Ky","Slice","FA","Echo" ; "NR","Ky","Slice","Kx","FA","Echo" ; "NR","Slice","Kx","Ky","FA","Echo" ; ...
                        "Kx","Ky","NR","Slice","FA","Echo" ; "Ky","Slice","NR","Kx","FA","Echo" ; "Slice","Kx","NR","Ky","FA","Echo" ; ...
                        "Kx","Ky","Slice","Echo","NR","FA" ; "Kx","Slice","Ky","Echo","NR","FA" ; "Ky","Slice","Kx","Echo","NR","FA" ];
                    
                    app.image_orient_label = ["X","Y","Slice","NR","FA","Echo" ; "Y","Slice","X","NR","FA","Echo" ; "Slice","X","Y","NR","FA","Echo" ; ...
                        "NR","X","Y","Slice","FA","Echo" ; "NR","Y","Slice","X","FA","Echo" ; "NR","Slice","X","Y","FA","Echo" ; ...
                        "X","Y","NR","Slice","FA","Echo" ; "Y","Slice","NR","X","FA","Echo" ; "Slice","X","NR","Y","FA","Echo" ; ...
                        "X","Y","Slice","Echo","NR","FA" ; "X","Slice","Y","Echo","NR","FA" ; "Y","Slice","X","Echo","NR","FA" ];
                    
            end
            
            app.OrientationSpinner.Value = 1;
            app.KOrientationSpinner.Value = 1;
            
            % Reco size
            app.XEditField.Value = app.KMatrixViewField1.Value;
            app.YEditField.Value = app.KMatrixViewField2.Value;
            app.ZEditField.Value = app.KMatrixViewField3.Value;
            app.NREditField.Value = app.NRViewField.Value;
            
            % Total scan time and scan time / dynamic
            app.CalcTotalAcquisitionTime;
            
        end % SetParametersFcn
        
        
        % Calculate the total acquisition time
        function CalcTotalAcquisitionTime(app)

            if app.rpr_flag

                pos = strfind(app.rpr,":IM_TEXT_DCM_AcquisitionDuration");
                txt = app.rpr(pos:pos+40);
                pos2 = strfind(txt,"""");
                tt = extractBetween(txt,pos2(1)+1,pos2(2)-1);
                app.totalacquisitiontime = str2num(tt{1});

            else

                app.totalacquisitiontime = app.seqpar.NO_VIEWS*app.seqpar.NO_SLICES*app.NEViewField.Value*app.NAViewField.Value*app.NFAViewField.Value*app.NRViewField.Value*app.seqpar.tr/1000;

                % FSE type sequences
                if contains(app.seqpar.PPL,'fse')
                    if isfield(app.seqpar,'VIEWS_PER_SEGMENT')
                        app.totalacquisitiontime = app.totalacquisitiontime/app.seqpar.VIEWS_PER_SEGMENT;
                    else
                        app.totalacquisitiontime = app.totalacquisitiontime/app.NEViewField.Value;
                    end
                    if isfield(app.seqpar,'NO_SLICES')
                        app.totalacquisitiontime = app.totalacquisitiontime/app.seqpar.NO_SLICES;
                    end
                end

                % FLASH type sequences
                if contains(app.seqpar.PPL,'flash')
                    app.totalacquisitiontime = app.totalacquisitiontime/app.NEViewField.Value;
                    if isfield(app.seqpar,'NO_SLICES') && isfield(app.seqpar,'SLICE_INTERLEAVE')
                        if app.seqpar.SLICE_INTERLEAVE == 2
                            app.totalacquisitiontime = app.totalacquisitiontime/app.seqpar.NO_SLICES;
                        end
                    end
                end

                if isfield(app.seqpar,'NO_VIEWS_2')
                    app.totalacquisitiontime = app.totalacquisitiontime*app.seqpar.NO_VIEWS_2;
                end


            end

            app.TimeDynViewField.Value = datestr(seconds(app.totalacquisitiontime/app.NREditField.Value),'HH:MM:SS.FFF');
            app.ScanTimeViewField.Value = datestr(seconds(app.totalacquisitiontime),'HH:MM:SS.FFF');


        end
        
        
        % Set the k-space imaging limits
        function SetKspaceLimitsFcn(app)
            
            app.TextMessage('Setting k-space parameter limits ....');
            
            % Coil spinner
            if app.ncoils>1
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 app.ncoils];
                app.CoilSpinner.Enable = 'on';
            else
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 2];
                app.CoilSpinner.Enable = 'off';
            end
            
            % Dimension 1 selection
            im = permute(abs(app.kspace{app.CoilSpinner.Value}),app.image_orient(app.KOrientationSpinner.Value,:));
            dim1size = size(im,3);
            app.KDim3EditField.Value = 1;
            app.KDim3Slider.Value = 1;
            if dim1size > 1
                app.KDim3EditField.Limits = [1,dim1size];
                app.KDim3Slider.Limits = [1,dim1size];
                app.KDim3EditField.Value = round(dim1size/2);
                app.KDim3Slider.Value = round(dim1size/2);
                app.KDim3EditField.Enable = 'on';
                app.KDim3Slider.Enable = 'on';
            else
                app.KDim3EditField.Limits = [1,2];
                app.KDim3Slider.Limits = [1,2];
                app.KDim3EditField.Enable = 'off';
                app.KDim3Slider.Enable = 'off';
            end
            
            % Dimension 2 selection
            dim2size = size(im,4);
            app.KDim4EditField.Value = 1;
            app.KDim4Slider.Value = 1;
            if dim2size > 1
                app.KDim4EditField.Limits = [1,dim2size];
                app.KDim4Slider.Limits = [1,dim2size];
                app.KDim4EditField.Value = round(dim2size/2);
                app.KDim4Slider.Value = round(dim2size/2);
                app.KDim4EditField.Enable = 'on';
                app.KDim4Slider.Enable = 'on';
            else
                app.KDim4EditField.Limits = [1,2];
                app.KDim4Slider.Limits = [1,2];
                app.KDim4EditField.Enable = 'off';
                app.KDim4Slider.Enable = 'off';
            end
            
            % Echo selection
            app.KEchoEditField.Value = 1;
            app.KEchoSlider.Value = 1;
            if app.more_echoes
                app.KEchoEditField.Limits = [1,app.NEViewField.Value];
                app.KEchoSlider.Limits = [1,app.NEViewField.Value];
                app.KEchoEditField.Enable = 'on';
                app.KEchoSlider.Enable = 'on';
            else
                app.KEchoEditField.Limits = [1,2];
                app.KEchoSlider.Limits = [1,2];
                app.KEchoEditField.Enable = 'off';
                app.KEchoSlider.Enable = 'off';
            end
            
            % Flip angle selection
            app.KFlipAngleEditField.Value = 1;
            app.KFlipAngleSlider.Value = 1;
            if app.more_flipangles
                app.KFlipAngleEditField.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleSlider.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleEditField.Enable = 'on';
                app.KFlipAngleSlider.Enable = 'on';
                
            else
                app.KFlipAngleEditField.Limits = [1,2];
                app.KFlipAngleSlider.Limits = [1,2];
                app.KFlipAngleEditField.Enable = 'off';
                app.KFlipAngleSlider.Enable = 'off';
            end
            
            % Label the orientations
            app.KxLabel.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,1);
            app.KyLabel.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,2);
            app.KDim3Label.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,3);
            app.KDim4Label.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,4);
            
            % Movie button
            app.KStopButton.Enable = 'off';
            
            app.buttonstatus = [app.KDim3EditField.Enable;app.KDim3Slider.Enable;app.KDim4EditField.Enable;app.KDim4Slider.Enable; ...
                app.KFlipAngleEditField.Enable;app.KFlipAngleSlider.Enable;app.KEchoEditField.Enable;app.KEchoSlider.Enable; ...
                app.KOrientationSpinner.Enable;app.fillingButton.Enable;app.averagesButton.Enable;app.kvaluesButton.Enable;app.CoilSpinner.Enable];
            
            
        end % SetKspaceLimitsFcn
        
        
        % Set the image limits
        function SetImageLimitsFcn(app)
            
            app.TextMessage('Setting image parameter limits ....');
            
            % Dimension 3 selection
            im = permute(abs(app.images),app.image_orient(app.OrientationSpinner.Value,:));
            dim3size = size(im,3);
            app.Dim3EditField.Value = 1;
            app.Dim3Slider.Value = 1;
            if dim3size > 1
                app.Dim3EditField.Limits = [1,dim3size];
                app.Dim3Slider.Limits = [1,dim3size];
                app.Dim3EditField.Value = round(dim3size/2);
                app.Dim3Slider.Value = round(dim3size/2);
                app.Dim3EditField.Enable = 'on';
                app.Dim3Slider.Enable = 'on';
            else
                app.Dim3EditField.Limits = [1,2];
                app.Dim3Slider.Limits = [1,2];
                app.Dim3EditField.Enable = 'off';
                app.Dim3Slider.Enable = 'off';
            end
            
            % Dimension 4 selection
            dim4size = size(im,4);
            app.Dim4EditField.Value = 1;
            app.Dim4Slider.Value = 1;
            if dim4size > 1
                app.Dim4EditField.Limits = [1,dim4size];
                app.Dim4Slider.Limits = [1,dim4size];
                app.Dim4EditField.Value = round(dim4size/2);
                app.Dim4Slider.Value = round(dim4size/2);
                app.Dim4EditField.Enable = 'on';
                app.Dim4Slider.Enable = 'on';
            else
                app.Dim4EditField.Limits = [1,2];
                app.Dim4Slider.Limits = [1,2];
                app.Dim4EditField.Enable = 'off';
                app.Dim4Slider.Enable = 'off';
            end
            
            % Dimension 5 selection
            dim5size = size(im,5);
            app.Dim5EditField.Value = 1;
            app.Dim5Slider.Value = 1;
            if dim5size > 1
                app.Dim5EditField.Limits = [1,dim5size];
                app.Dim5Slider.Limits = [1,dim5size];
                app.Dim5EditField.Enable = 'on';
                app.Dim5Slider.Enable = 'on';
            else
                app.Dim5EditField.Limits = [1,2];
                app.Dim5Slider.Limits = [1,2];
                app.Dim5EditField.Enable = 'off';
                app.Dim5Slider.Enable = 'off';
            end
            
            % Flip angle selection
            dim6size = size(im,6);
            app.Dim6EditField.Value = 1;
            app.Dim6Slider.Value = 1;
            if dim6size > 1
                app.Dim6EditField.Limits = [1,dim6size];
                app.Dim6Slider.Limits = [1,dim6size];
                app.Dim6EditField.Enable = 'on';
                app.Dim6Slider.Enable = 'on';
                
            else
                app.Dim6EditField.Limits = [1,2];
                app.Dim6Slider.Limits = [1,2];
                app.Dim6EditField.Enable = 'off';
                app.Dim6Slider.Enable = 'off';
            end
            
            % Label the orientations
            app.XLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,1);
            app.YLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,2);
            app.Dim3Label.Text = app.image_orient_label(app.OrientationSpinner.Value,3);
            app.Dim4Label.Text = app.image_orient_label(app.OrientationSpinner.Value,4);
            app.Dim5Label.Text = app.image_orient_label(app.OrientationSpinner.Value,5);
            app.Dim6Label.Text = app.image_orient_label(app.OrientationSpinner.Value,6);
            
            % Movie button
            if app.stopmovie
                app.StartButton.Enable = 'on';
                app.StopButton.Enable = 'off';
            else
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'on';
            end
            
        end % SetImageLimitsFcn
        
        
        % Sort and fill k-space
        function FillKspaceFcn(app)
            
            % Indicate busy
            app.TextMessage('Filling k-space ....');
            set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
            app.SetStatus(3);
            
            if app.valid_file
                
                app.nsa_space = app.seqpar.NO_AVERAGES * ones(size(app.kspace{1}));
                app.filling_space = ones(size(app.kspace{1}));
                app.validtrajectory = false;
                
                switch app.traj_type
                    
                    case "rtable"
                        
                        try
                            if (exist(strcat(app.mrd_import_path,filesep,'rtable.rtv'), 'file') == 2)
                                scanner_kspace_table = load(strcat(app.mrd_import_path,filesep,'rtable.rtv'));
                            elseif (exist(strcat(app.mrd_import_path,filesep,'origrtable.rtv.orig'), 'file') == 2)
                                scanner_kspace_table = load(strcat(app.mrd_import_path,filesep,'origrtable.rtv.orig'));
                            end
                            for i=1:app.ncoils
                                [app.kspace{i},app.seqtrajectory] = sortscannerkspaceMRD(app,app.kspace{i},app.seqpar,scanner_kspace_table);
                            end
                            app.validtrajectory = true;
                        catch ME
                            app.TextMessage('ERROR: something went wrong sorting data with rtable.rtv file ....');
                            app.TextMessage(ME.message);
                            app.SetStatus(2);
                        end
                        
                        
                    case "mul_kspace"
                        
                        app.seqpar.NO_VIEWS_ORIG = app.seqpar.NO_VIEWS;
                        maxk = max([app.seqpar.gp_var_mul,-app.seqpar.gp_var_mul]);
                        if rem(maxk,2) == 0
                            app.seqpar.NO_VIEWS = 2*maxk;
                        else
                            app.seqpar.NO_VIEWS = 2*(maxk+1);
                        end
                        app.YEditField.Value = app.seqpar.NO_VIEWS;
                        app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                        app.seqpar.NO_SAMPLES_ORIG = app.seqpar.NO_SAMPLES;
                        app.validtrajectory = true;
                        
                        % sort
                        switch app.data_type
                            case "2D"
                                for i=1:app.ncoils
                                    [app.kspace{i},app.nsa_space,app.filling_space,app.seqtrajectory] = sort2DkspaceMRD(app,app.seqpar,app.ukspace{i},app.NRViewField.Value);
                                end
                            case "3D"
                                for i=1:app.ncoils
                                    [app.kspace{i},app.nsa_space,app.filling_space,app.seqtrajectory] = sort3DkspaceMRD(app,app.seqpar,app.ukspace{i},app.NRViewField.Value);
                                end
                        end
                        
                    case "P2ROUD"
                        
                        app.seqpar.NO_VIEWS_ORIG = app.seqpar.NO_VIEWS;
                        app.seqpar.NO_VIEWS_2_ORIG = app.seqpar.NO_VIEWS_2;
                        app.seqpar.proudarray = reshape(app.seqpar.gp_var_proud,[2,length(app.seqpar.gp_var_proud)/2]);
                        maxky = max([app.seqpar.proudarray(1,:),-app.seqpar.proudarray(1,:)]);
                        if rem(maxky,2) == 0
                            app.seqpar.NO_VIEWS = 2*maxky;
                        else
                            app.seqpar.NO_VIEWS = 2*(maxky+1);
                        end
                        maxkz = max([app.seqpar.proudarray(2,:),-app.seqpar.proudarray(2,:)]);
                        if rem(maxkz,2) == 0
                            app.seqpar.NO_VIEWS_2 = 2*maxkz;
                        else
                            app.seqpar.NO_VIEWS_2 = 2*(maxkz+1);
                        end
                        app.YEditField.Value = app.seqpar.NO_VIEWS;
                        app.ZEditField.Value = app.seqpar.NO_VIEWS_2;
                        app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                        app.KMatrixViewField3.Value = app.seqpar.NO_VIEWS_2;
                        app.validtrajectory = true;
                        
                        % sort
                        for i=1:app.ncoils
                            [app.kspace{i},app.nsa_space,app.filling_space,app.seqtrajectory] = sortPROUDkspaceMRD(app,app.seqpar,app.ukspace{i},app.NRViewField.Value);
                        end
                        
                end
                
                % Chop of the navigator if present
                if isfield(app.seqpar, 'slice_nav')
                    if app.seqpar.slice_nav == 1
                        discard = 35+app.seqpar.no_samples_nav;
                        for i=1:app.ncoils
                            app.kspace{i} = app.kspace{i}(discard+1:end,:,:,:,:,:);
                        end
                        app.nsa_space = app.nsa_space(discard+1:end,:,:,:,:,:);
                        app.filling_space = app.filling_space(discard+1:end,:,:,:,:,:);
                        app.seqpar.NO_SAMPLES = app.seqpar.NO_SAMPLES - discard;
                        app.XEditField.Value = app.seqpar.NO_SAMPLES;
                        app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                    end
                end
                
            end
            
            % K-space filling percentage, without cropping, dynamic interpolation, or zero-filling
            app.FillingViewField.Value = round(100*nnz(app.filling_space)/numel(app.filling_space));
            
            % Apply Tukey k-space filter
            app.TextMessage('Applying Tukey k-space filter ...');
            filterwidth = 0.1;
            dimx = size(app.kspace{1},1);
            dimy = size(app.kspace{1},2);
            dimz = size(app.kspace{1},3);
            switch app.data_type
                case {"2D","2Dradial"}
                    kspacesum = zeros(dimx,dimy);
                    for i=1:app.ncoils
                        kspacesum = kspacesum + squeeze(sum(app.kspace{i},[3 4 5 6 7 8])); 
                    end
                    [row, col] = find(ismember(kspacesum, max(kspacesum(:))));        
                    for i = 1:app.ncoils
                        flt = circtukey2D(dimx,dimy,row,col,filterwidth);
                        tukeyfilter(:,:,1,1,1,1) = flt;
                        app.kspace{i} = app.kspace{i}.*tukeyfilter;
                    end
                case "3D"
                    kspacesum = zeros(dimx,dimy,dimz);
                    for i=1:app.ncoils
                        kspacesum = kspacesum + squeeze(sum(app.kspace{i},[4 5 6 7 8]));             
                    end
                    [~,idx] = max(kspacesum(:));
                    [lev, row, col] = ind2sub(size(kspacesum),idx); 
                    for i=1:app.ncoils
                        flt = circtukey3D(dimx,dimy,dimz,lev,row,col,filterwidth);
                        tukeyfilter(:,:,:,1,1,1) = flt;
                        app.kspace{i} = app.kspace{i}.*tukeyfilter;
                    end
            end
            
            % For multi-gradient-echo type sequence, flip odd k-spaces
            cine = false;
            if isfield(app.seqpar,'frame_loop_on')
                if (app.seqpar.frame_loop_on == 1)
                    cine = true;
                end
            end
            if contains(app.seqpar.PPL,'flash') && cine
               app.TextMessage('CINE data ...');
               if app.NEViewField.Value > 1
                    for j = 2:2:app.NEViewField.Value
                        for i = 1:app.ncoils
                           % for some reason flipping seems not necessary anymore
                           % app.kspace{i}(:,:,:,:,:,j) = flip(app.kspace{i}(:,:,:,:,:,j),1);
                        end
                    end
                end
            end
            
            % Set k-space level and window limits
            for i=1:app.ncoils
                mx1(i) = max(abs(app.kspace{i}(:)));
            end
            mx = round(max(mx1(:)));
            
            app.KLevelEditField.Limits = [0 mx+1];
            app.KLevelSlider.Limits = [0 mx+1];
            app.KWindowEditField.Limits = [0 mx+1];
            app.KWindowSlider.Limits = [0 mx+1];
            app.SetKspaceIntensity;
            app.KWindowEditFieldValueChanged;
            app.KLevelEditFieldValueChanged;
            
            % Reset mouse pointer
            set(app.P2ROUD_UIFigure, 'Pointer', 'arrow');
            app.SetStatus(0);
            
        end % FillKspaceFcn
        
        
        % Export the reconstruction settings
        function ExportRecoParametersFcn(app,exportdir)
            
            pars = strcat(...
                "\nP2ROUD\n", ...
                "------------------------- \n\n", ...
                "datafile = ", app.MRDfileViewField.Value, "\n", ...
                "sequence = ", app.SequenceViewField.Value, "\n\n",...    
                "\nDIMENSIONS \n\n", ...
                "dim x = ", num2str(app.XEditField.Value), "\n", ...
                "dim y = ", num2str(app.YEditField.Value), "\n", ...
                "dim z = ", num2str(app.ZEditField.Value) , "\n", ...
                "FOV x = ", num2str(app.FOVViewField1.Value), " mm \n", ...
                "FOV y = ", num2str(app.FOVViewField2.Value), " mm \n", ...
                "FOV z = ", num2str(app.FOVViewField3.Value), " mm \n", ...
                "k x = ", num2str(app.KMatrixViewField1.Value), "\n", ...
                "k y = ", num2str(app.KMatrixViewField2.Value), "\n", ...
                "k z = ", num2str(app.KMatrixViewField3.Value), "\n", ...
                "#repetitions = ", num2str(app.NREditField.Value), "\n\n", ...
                "\nSCAN PARAMETERS\n\n", ...
                "scan time = ", app.ScanTimeViewField.Value, "\n", ...
                "time per dynamic = ", app.TimeDynViewField.Value, "\n", ...
                "TR = ", num2str(app.TRViewField.Value), " ms \n", ...
                "TE = ", num2str(app.TEViewField.Value), " ms \n", ...
                "#echoes = ", num2str(app.NEViewField.Value), "\n", ...
                "#averages = ", num2str(app.NAViewField.Value), "\n", ...
                "#flip angles = ", num2str(app.NFAViewField.Value), "\n", ...
                "flip angles = ", app.FAViewField.Value, "\n", ...
                "#repetitions = ", num2str(app.NRViewField.Value), "\n", ...
                "trajectory = ", app.Trajectory.Value, "\n\n", ...
                "\nRECONSTRUCTION PARAMETERS\n\n", ...
                "Wavelet = ",num2str(app.WVxyzEditField.Value), "\n", ...
                "TVxyz = ",num2str(app.TVxyzEditField.Value), "\n", ...
                "LRxyz = ",num2str(app.LRxyzEditField.Value), "\n", ...
                "TVtime = ",num2str(app.TVtimeEditField.Value), "\n", ...
                "CSreco = ",num2str(app.CSRecoCheckBox.Value), "\n" ...
                );
            
            fid = fopen(strcat(exportdir,filesep,'recoparmeters_',app.tag,'.txt'),'wt');
            fprintf(fid,pars);
            fclose(fid);
            
        end


        % --------------------------------------------------------------
        %
        %                           FIGURES
        %
        % --------------------------------------------------------------
        
        
        % Calculate the k-space image aspect ratio
        function result = CalcKAspectratioFnc(app,dim1label,dim2label)
            
            if strcmp(dim1label,'Kx') L1 = app.FOVViewField1.Value; end
            if strcmp(dim1label,'Ky') L1 = app.FOVViewField2.Value; end
            if strcmp(dim1label,'Kz') L1 = app.FOVViewField3.Value; end
            if strcmp(dim1label,'NR') L1 = min([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1label,'Slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            if strcmp(dim2label,'Kx') L2 = app.FOVViewField1.Value; end
            if strcmp(dim2label,'Ky') L2 = app.FOVViewField2.Value; end
            if strcmp(dim2label,'Kz') L2 = app.FOVViewField3.Value; end
            if strcmp(dim2label,'NR') L2 = min([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2label,'Slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            result = (L1/L2);
            
        end % Aspectratio
        
        
        % Calculate the image aspect ratio
        function result = CalcAspectratioFnc(app,dim1label,dim2label,sizex,sizey)
            
            if strcmp(dim1label,'X') L1 = app.FOVViewField1.Value; end
            if strcmp(dim1label,'Y') L1 = app.FOVViewField2.Value; end
            if strcmp(dim1label,'Z') L1 = app.FOVViewField3.Value; end
            if strcmp(dim1label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1label,'Slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            if strcmp(dim2label,'X') L2 = app.FOVViewField1.Value; end
            if strcmp(dim2label,'Y') L2 = app.FOVViewField2.Value; end
            if strcmp(dim2label,'Z') L2 = app.FOVViewField3.Value; end
            if strcmp(dim2label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2label,'Slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            result = (L2/L1)*(sizex/sizey);
            
        end % Aspectratio
        
        
        % Set the image window and level
        function SetIntensity(app)
            
            if app.valid_reco
                
                % Select the data
                im = app.images;
                
                im = permute(im,app.image_orient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.Dim3EditField.Value,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));
                
                scale = max(im(:));
                app.level = round(scale/3.0);
                app.window = round(scale/1.5);
          
                app.LevelSlider.Value = double(app.level);
                app.WindowSlider.Value = double(app.window);
                app.LevelEditField.Value = round(app.level);
                app.WindowEditField.Value = round(app.window);
                
            end
            
        end % SetIntensity
        
         % Set the k-space image window and level
        function SetKspaceIntensity(app)
            
            if app.valid_file
                
                % Select the data
                im = abs(app.kspace{app.CoilSpinner.Value});
                
                % Select which slice to show
                im = permute(im,app.image_orient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));
                
                scale = max(im(:));
                app.klevel = round(scale/3.0);
                app.kwindow = round(scale/1.5);
                
                app.KLevelSlider.Value = double(app.klevel);
                app.KWindowSlider.Value = double(app.kwindow);
                app.KLevelEditField.Value = round(app.klevel);
                app.KWindowEditField.Value = round(app.kwindow);
                
            end
            
        end % SetIntensity
        
        
        % Plot k-space
        function PlotKspaceDataFig(app)
            
            if app.valid_file
                
                cla(app.KspaceFig);
                delete(app.cbar);
                
                % Select the data
                if app.fillingButton.Value == 1
                    im = app.filling_space;
                    cmap = [0 0 0 ; summer(1)];
                    minscale = 0;
                    maxscale = max(im(:));
                end
                
                if app.averagesButton.Value == 1
                    im = app.nsa_space;
                    cmap = [0 0 0 ; summer(max(im(:)))];
                    minscale = 0;
                    maxscale = max(im(:));
                    app.cbar = colorbar(app.KspaceFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);
                end
                
                if app.kvaluesButton.Value == 1
                    im = abs(app.kspace{app.CoilSpinner.Value});
                    cmap = gray;
                    minscale = app.klevel - app.kwindow/2;
                    maxscale = app.klevel + app.kwindow/2;
                    if minscale > maxscale
                        maxscale = minscale + 1;
                    end
                end
                
                % Plot the image in the image panel
                app.KspaceFig.Position = [34,87,325,325];
                
                % Select which slice to show
                im = permute(im,app.image_orient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));
                
                % Plot the image in the k-space images panel
                imshow(rot90(im),[minscale maxscale],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.KspaceFig);
                
                % Calculate aspect ratio
                recoaspectratio = CalcKAspectratioFnc(app,app.KxLabel.Text,app.KyLabel.Text);
                daspect(app.KspaceFig,[recoaspectratio 1 1]);
                
                % Size of the matrix
                xlim(app.KspaceFig,[0, size(im,1) + 1]);
                ylim(app.KspaceFig,[0, size(im,2) + 1]);
                
                drawnow;
                
            end
            
        end % PlotKspaceDataFig
        
        
        % Plot the reconstructed image
        function PlotImageDataFig(app)
            
            if app.valid_reco
                
                % Select the data
                im = app.images;
                
                % Select which slice to show
                im = permute(im,app.image_orient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.Dim3EditField.Value,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));
                               
                % Image scale
                if app.AutoIntensityCheckBox.Value == 1
                    scale = max(im(:));
                    app.level = round(scale/2.0);
                    app.window = round(scale/1.0);
                    app.LevelSlider.Value = app.level;
                    app.WindowSlider.Value = app.window;
                    app.LevelEditField.Value = round(app.level);
                    app.WindowEditField.Value = round(app.window);
                end
                minscale = app.level - app.window/2;
                maxscale = app.level + app.window/2;
                if minscale >= maxscale
                    maxscale = minscale + 1;
                end
                
                % Image aspect ratio & dimensions
                recoaspectratio = CalcAspectratioFnc(app,app.XLabel.Text,app.YLabel.Text,size(im,1),size(im,2));
                
                % If phase orientation = 1 rotate image (for viewing purposes only)
                if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.seqpar.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    recoaspectratio = CalcAspectratioFnc(app,app.YLabel.Text,app.XLabel.Text,size(im,1),size(im,2));
                end
                
                % Plot the image in the images panel
                if app.newfigflag
                     
                    % Plot the image in the image panel
                    cla(app.RecoFig);
                    app.RecoFig.Position = [43,87,325,325];
                    
                     % New fig with changed properties
                    app.recoimage = imshow(rot90(im),[minscale maxscale],'InitialMagnification','fit','Parent',app.RecoFig);
                
                    % Aspect ratio and dimensions
                    daspect(app.RecoFig,[recoaspectratio 1 1]);
                    xlim(app.RecoFig,[0, size(im,1) + 1]);
                    ylim(app.RecoFig,[0, size(im,2) + 1]);
                    
                    app.newfigflag = false;
                 
                else
                
                    % Only new data in the figure = fast
                    set(app.recoimage, 'CData', rot90(im));
                    set(app.recoimage, 'CDataMapping', 'scaled');
                    
                    app.RecoFig.CLim = [minscale maxscale];
                    pause(0.002);
                   
                end
                   
                drawnow;
                 
            end
            
        end % PlotImageDataFig
        
        
    end


    % --------------------------------------------------------------
    %
    %                       PUBLIC FUNCTIONS
    %
    % --------------------------------------------------------------
    
    methods (Access = public)
        
        
        % Text messages in the app
        function TextMessage(app, message)
            
            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];
                drawnow;
            catch
            end
            
        end % TextMessage
        
        % Status icon
        function SetStatus(app,status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;
                    
                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'warning';
                    app.statusLabel.FontColor = app.orange;
                    
                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'error';
                    app.statusLabel.FontColor = app.brightred;
                    
                case 3
                    app.StatusImage.ImageSource = 'statuswait.gif';
                    app.statusLabel.Text = 'busy';
                    app.statusLabel.FontColor = 'black';
                    
            end
            
            drawnow;
            
        end
        
    end


    % --------------------------------------------------------------
    %
    %                   USER INTERFACE CODE
    %
    % --------------------------------------------------------------
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Suppress unneccesary warnings
            warning off;
            clc;
            
            % Add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('P2ROUD.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
                rmpath("P2ROUD/");
                rmpath("P2ROUD_resources/");
            end
            
            % Set the app layout
            app.SetAppLayout;
            app.SetStatus(0);
            app.SwitchOffAllButtons;
            
            % Empty file import and export paths
            app.mrd_import_path = '';
            app.mrd_flag = true;
            app.dicom_export_path = '';
            app.dicom_flag = true;
            app.stopmovie = true;
            app.newfigflag = true;
            
            % Add the Bart path to the environment variable if available
            try
                folder_name = fileread('bartpath.txt');
                setenv('TOOLBOX_PATH', folder_name);
            catch ME
                app.TextMessage(ME.message);
            end
            
            % Check whether Bart is available or not
            try
                app.bart_detected = true;
                bart(app,'version');
            catch ME
                app.bart_detected = false;
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.TextMessage(ME.message);
                app.SetStatus(1);
            end
            if app.bart_detected
                app.TextMessage('BART toolbox detected ...');
            end
            
            % Initialize buttons
            app.SetLights({'Red','Red','Red','Red','Red','Red'});
            app.SetButtons({'On','Off','Off','Off','Off','Off','On'});
            app.SetRecoFields;
            
        end

        % Button pushed function: LoadMRDButton
        function LoadMRDButtonPushed(app, event)
            
            
            % Set buttons
            app.SwitchOffAllButtons;
            app.SetLights({'Yellow','Red','Red','Red','Red','Red'});
            app.SetStatus(0);
            
            % Check standard smis directory first if platform is pc, only 1x per session
            if ispc
                try
                    folder_name = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folder_name = [folder_name,filesep];
                catch ME
                    folder_name = 'c:\smis\dev\MRD\';
                    app.TextMessage(ME.message);
                end
                if exist(folder_name, 'dir') && app.mrd_flag
                    app.mrd_import_path = folder_name;
                    app.mrd_flag = false;
                end
            end
            
            % Check mrdpath.txt file next, only 1x per session
            if isfile('mrdpath.txt')  && app.mrd_flag
                try
                    folder_name = fileread('mrdpath.txt');
                    if exist(folder_name, 'dir')
                        app.mrd_import_path = folder_name;
                        app.mrd_flag = false;
                    end
                catch ME
                    app.TextMessage(ME.message);
                    app.mrd_import_path = '';
                    app.mrd_flag = false;
                end
            end
            
            % Select 1 MRD file
            app.P2ROUD_UIFigure.Visible = 'off';
            [mrdfile,app.mrd_import_path] = uigetfile(strcat(app.mrd_import_path,filesep,'*.MRD'));
            app.P2ROUD_UIFigure.Visible = 'on';
            
            % Initial settings
            app.valid_file = false;
            app.valid_reco = false;
            app.more_echoes = false;
            app.more_repetitions = false;
            app.more_flipangles = false;
            app.stopmovie = true;
            app.kstopmovie = true;
            app.newfigflag = true;
            
            if ischar(mrdfile)
                
                % Indicate busy
                set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
                app.SetStatus(3);

                try

                    % MRD file list, check for multiple file names with the same name = multicoil
                    app.MRDfileViewField.Value = mrdfile;
                    k = strfind(mrdfile,'_');
                    if length(k)>1
                        mrdstart = extractBefore(mrdfile,k(end-1));
                    else
                        mrdstart = extractBefore(mrdfile,k(end));
                    end
                    mrdfile = [app.mrd_import_path,mrdfile];
                    flist = dir(fullfile(app.mrd_import_path,[mrdstart,'*.MRD']));

                    % Load the data
                    app.TextMessage('Reading MRD file(s) ...');

                    % Determine the number of coils
                    app.multicoil = false;
                    app.ncoils = length(flist);
                    if app.ncoils>1
                        app.multicoil = true;
                        app.TextMessage('Multi receiver coil data detected ...');
                    end
                    app.ResetCoilButtons;

                    app.kspace = {};
                    app.proudrecoscan = false;
                    if contains(mrdfile,'p2roud')
                        app.proudrecoscan = true;
                        app.TextMessage('P2ROUD reconstructed scan ...');
                        for i=1:app.ncoils
                            app.TextMessage(strcat('Loading coil #',num2str(i)));
                            [app.kspace{i},~,app.seqpar,app.ukspace{i}] = Get_mrd_3D5(fullfile(flist(i).folder,flist(i).name),'seq','seq');
                        end
                    elseif contains(mrdfile,'retro')
                        app.TextMessage('RETROSPECTIVE reconstructed scan ...');
                        for i=1:app.ncoils
                            app.TextMessage(strcat('Loading coil #',num2str(i)));
                            [app.kspace{i},~,app.seqpar,app.ukspace{i}] = Get_mrd_3D5(fullfile(flist(i).folder,flist(i).name),'seq','seq');
                        end
                    else
                        for i=1:app.ncoils
                            app.TextMessage(strcat('Loading coil #',num2str(i)));
                            [app.kspace{i},~,app.seqpar,app.ukspace{i}] = Get_mrd_3D5(fullfile(flist(i).folder,flist(i).name),'seq','cen');
                        end
                        if isfield(app.seqpar,'pe2_centric_on') && ~contains(app.seqpar.PPL,'fse')
                            if app.seqpar.pe2_centric_on == 0
                                app.TextMessage('pe2_order = sequential ...');
                                for i=1:app.ncoils
                                    app.TextMessage(strcat('Re-loading coil #',num2str(i)));
                                    [app.kspace{i},~,app.seqpar,app.ukspace{i}] = Get_mrd_3D5(fullfile(flist(i).folder,flist(i).name),'seq','seq');
                                end
                            end
                        end
                    end

                    % Flag for valid data
                    app.valid_file = true;

                    % Filename tag
                    [~,app.tag,~] = fileparts([flist(1).folder,filesep,flist(1).name]);

                    % read the full mrd-file footer
                    app.mrdfooter = readmrdfooter(fullfile(flist(1).folder,flist(1).name));

                catch ME

                    app.TextMessage('ERROR: during file loading ...');
                    app.TextMessage(ME.message);
                    app.SetStatus(2);

                end
                
                
                % Read the rpr file
                try
                    rprfilename = [flist(1).folder,filesep,app.tag,'.rpr'];
                    app.rpr = readrprfile(rprfilename);
                    app.rpr_flag = true;
                catch ME
                    app.TextMessage('WARNING: rpr file not found ...');
                    app.TextMessage(ME.message);
                    app.SetStatus(1);
                    app.rpr_flag = false;
                end
                
                % Determine the sequence file name
                app.seqpar.PPL = replace(app.seqpar.PPL,'\',filesep);
                [~,name,ext] = fileparts(app.seqpar.PPL);
                app.seqpar.PPL = strcat(name,ext);
                
                % Reset pointer
                set(app.P2ROUD_UIFigure, 'Pointer', 'arrow');
                
            end
            
            % Report on valid/invalid file selection
            if app.valid_file
                
                % Set other parameters
                app.SetParametersFcn;
                app.SetKspaceLimitsFcn;
                app.FillKspaceFcn;
                app.PlotKspaceDataFig;
                
                app.TextMessage('Data import ready ...');
                app.TextMessage(strcat('Size of k-space =',{' '},num2str(size(app.kspace{1}))));
                
                % Set buttons
                app.SetLights({'Green','Red','Red','Red','Red','Red'});
                app.SetButtons({'On','On','Null','Null','Null','Null','On'});
                app.SetStatus(0);
                
            else
                
                % Not a valid data file
                app.TextMessage('ERROR: Please select a valid data file');
                app.SetStatus(2);
                
                % Set the buttons for invalid data file
                app.SetLights({'Red','Red','Red','Red','Red','Red'});
                app.SetButtons({'On','Off','Off','Off','Off','Off','On'});
                
            end
            
        end

        % Value changed function: KFlipAngleEditField
        function KFlipAngleEditFieldValueChanged(app, event)
            
            if app.kstopmovie
                app.KFlipAngleSlider.Value = app.KFlipAngleEditField.Value;
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: KFlipAngleSlider
        function KFlipAngleSliderValueChanged(app, event)
            
            if app.kstopmovie
                app.KFlipAngleEditField.Value = round(app.KFlipAngleSlider.Value);
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: KDim4EditField
        function KDim4EditFieldValueChanged(app, event)
            
            if app.kstopmovie
                app.KDim4Slider.Value = app.KDim4EditField.Value;
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: KDim4Slider
        function KDim4SliderValueChanged(app, event)
            
            if app.kstopmovie
                app.KDim4EditField.Value = round(app.KDim4Slider.Value);
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: KEchoEditField
        function KEchoEditFieldValueChanged(app, event)
            
            if app.kstopmovie
                app.KEchoSlider.Value = app.KEchoEditField.Value;
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: KEchoSlider
        function KEchoSliderValueChanged(app, event)
            
            if app.kstopmovie
                app.KEchoEditField.Value = round(app.KEchoSlider.Value);
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: KDim3EditField
        function KDim3EditFieldValueChanged(app, event)
            
            if app.kstopmovie
                app.KDim3Slider.Value = app.KDim3EditField.Value;
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: KDim3Slider
        function KDim3SliderValueChanged(app, event)
            
            if app.kstopmovie
                app.KDim3EditField.Value = round(app.KDim3Slider.Value);
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: KOrientationSpinner
        function KOrientationSpinnerValueChanged(app, event)
            
            if app.kstopmovie
                app.SetKspaceLimitsFcn;
                app.PlotKspaceDataFig;
            end
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            
            if app.kstopmovie
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: CoilSpinner
        function CoilSpinnerValueChanged(app, event)
            
            if app.kstopmovie
                app.PlotKspaceDataFig;
            end
            
        end

        % Value changed function: S1EditField, S2EditField, 
        % S3EditField, S4EditField, S5EditField, S6EditField, 
        % S7EditField, S8EditField
        function SensitivityEditFieldValueChanged(app, event)
            
               app.coilsensitivities = [app.S1EditField.Value app.S2EditField.Value app.S3EditField.Value app.S4EditField.Value ... 
                 app.S5EditField.Value app.S6EditField.Value app.S7EditField.Value app.S8EditField.Value];
             
            
        end

        % Value changed function: C1CheckBox, C2CheckBox, 
        % C3CheckBox, C4CheckBox, C5CheckBox, C6CheckBox, 
        % C7CheckBox, C8CheckBox
        function CoilCheckBoxValueChanged(app, event)
            
            app.coilactive = [app.C1CheckBox.Value app.C2CheckBox.Value app.C3CheckBox.Value app.C4CheckBox.Value ...
                app.C5CheckBox.Value app.C6CheckBox.Value app.C7CheckBox.Value app.C8CheckBox.Value];
            
        end

        % Button pushed function: KStartButton
        function KStartButtonPushed(app, event)
            
            if app.validtrajectory
                
                % Indicate busy
                app.SetStatus(3);
                app.kstopmovie = false;
                app.DisableKbuttons;
                app.KStopButton.Enable = 'on';
                app.KStartButton.Enable = 'off';
                
                % Set the image dimensions
                im = abs(app.kspace{app.CoilSpinner.Value});
                app.KspaceFig.Position = [34,87,325,325];
                im = permute(im,app.image_orient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));
                recoaspectratio = CalcKAspectratioFnc(app,app.KxLabel.Text,app.KyLabel.Text);
                daspect(app.KspaceFig,[recoaspectratio 1 1]);
                sizex = size(im,1);
                sizey = size(im,2);
                
                hold(app.KspaceFig,'on');
                delete(app.kmoviefig);
                
                % Get plot size in pixel units
                originalUnits = app.KspaceFig.Units;
                axh.Units = 'Points';
                axSizePix = app.KspaceFig.Position(3:4);
                axh.Units = originalUnits;
                pixPerUnit = axSizePix ./ [range(1:sizex),range(1:sizey)];  %pixels per unit
                
                % Initialize scatter plot
                app.kmoviefig = scatter(app.KspaceFig,1,1,ceil(median(pixPerUnit))^2,"yellow",'filled','s','MarkerFaceAlpha',0.5);
                xlim(app.KspaceFig,[0, sizex+1]);
                ylim(app.KspaceFig,[0, sizey+1]);
                hold(app.KspaceFig,'off');
                
                % K-space list that will be displayed
                pnts = app.seqtrajectory(:,app.image_orient(app.KOrientationSpinner.Value,:));
                pnts = pnts(pnts(:,3) == app.KDim3EditField.Value,:);
                pnts = pnts(pnts(:,4) == app.KDim4EditField.Value,:);
                
                % Accelerate viewing by stepping faster through the list
                stepsize = round(length(pnts)/500);
                stepsize(stepsize < 1) = 1;
                
                % Loop through the list and plot the k-space points
                for cnt = 1:stepsize:length(pnts)
                    
                    app.kmoviefig.XData = pnts(1:cnt,1);
                    app.kmoviefig.YData = sizey + 1 - pnts(1:cnt,2);
                    
                    if app.FastSlowSwitch.Value == "slow"
                        drawnow;
                        pause(0.002);
                    else
                        drawnow limitrate;
                    end
                    
                    if app.kstopmovie
                        break;
                    end
                    
                end
                
                % Finish the plot by showing all points
                app.kmoviefig.XData = pnts(:,1);
                app.kmoviefig.YData = sizey + 1 - pnts(:,2);
                
            else
                
                app.kstopmovie = true;
                app.TextMessage('K-space trajectory movie not possible for this type of data ...');
                
            end
            
            % Reset start/stop buttons
            app.kstopmovie = true;
            app.KStopButton.Enable = 'off';
            app.KStartButton.Enable = 'on';
            drawnow;
            
            % Set status ok
            app.SetStatus(0);
            app.EnableKbuttons;
            
        end

        % Button pushed function: KStopButton
        function KStopButtonPushed(app, event)
            
            app.kstopmovie = true;
            app.KStopButton.Enable = 'off';
            drawnow;
            
        end

        % Value changed function: FastSlowSwitch
        function FastSlowSwitchValueChanged(app, event)
            
            drawnow;
            
        end

        % Value changed function: KLevelEditField
        function KLevelEditFieldValueChanged(app, event)
            
            if app.valid_file
                
                app.KLevelSlider.Value = app.KLevelEditField.Value;
                app.klevel = app.KLevelEditField.Value;
                app.PlotKspaceDataFig;
                
            end
            
        end

        % Value changed function: KWindowEditField
        function KWindowEditFieldValueChanged(app, event)
            
            if app.valid_file
                
                app.KWindowSlider.Value = app.KWindowEditField.Value;
                app.kwindow = app.KWindowEditField.Value;
                app.PlotKspaceDataFig;
                
            end
            
        end

        % Value changed function: KLevelSlider
        function KLevelSliderValueChanged(app, event)
            
            if app.valid_file
                
                app.KLevelEditField.Value = round(app.KLevelSlider.Value);
                app.klevel = app.KLevelEditField.Value;
                app.PlotKspaceDataFig;
                
            end
            
        end

        % Value changed function: KWindowSlider
        function KWindowSliderValueChanged(app, event)
            
            if app.valid_file
                
                app.KWindowEditField.Value = round(app.KWindowSlider.Value);
                app.kwindow = app.KWindowEditField.Value;
                app.PlotKspaceDataFig;
                
            end
            
        end

        % Value changed function: NREditField
        function NREditFieldValueChanged(app, event)
            
            % Set buttons
            app.SetLights({'Yellow','Null','Null','Null','Null','Null'});
            app.SwitchOffAllButtons;
            app.SetStatus(3);
            
            % Stop the movies, if running
            app.stopmovie = true;
            app.kstopmovie = true;
            drawnow;
            
            % Re-sort the data in the new desired number of frames
            switch app.traj_type
                
                case "P2ROUD"
                    
                    for i=1:app.ncoils
                        [app.kspace{i},app.nsa_space,app.filling_space,app.seqtrajectory] = sortPROUDkspaceMRD(app,app.seqpar,app.ukspace{i},app.NREditField.Value);
                    end
                    
                    
                case "mul_kspace"
                    
                    switch app.data_type
                        
                        case "2D"
                            
                            for i=1:app.ncoils
                                [app.kspace{i},app.nsa_space,app.filling_space,app.seqtrajectory] = sort2DkspaceMRD(app,app.seqpar,app.ukspace{i},app.NREditField.Value);
                            end
                            
                        case "3D"
                            
                            for i=1:app.ncoils
                                [app.kspace{i},app.nsa_space,app.filling_space,app.seqtrajectory] = sort3DkspaceMRD(app,app.seqpar,app.ukspace{i},app.NREditField.Value);
                            end
                            
                    end
                    
            end
            
            % Chop of the navigator if present
            if isfield(app.seqpar, 'slice_nav')
                if app.seqpar.slice_nav == 1
                    discard = 35+app.seqpar.no_samples_nav;
                    for i=1:app.ncoils
                        app.kspace{i} = app.kspace{i}(discard+1:end,:,:,:,:,:);
                    end
                    app.nsa_space = app.nsa_space(discard+1:end,:,:,:,:,:);
                    app.filling_space = app.filling_space(discard+1:end,:,:,:,:,:);
                    app.seqpar.NO_SAMPLES = size(app.kspace{1},1);
                    app.XEditField.Value = app.seqpar.NO_SAMPLES;
                    app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                end
            end
            
            % K-space filling percentage, without cropping, dynamic interpolation, or zero-filling
            app.FillingViewField.Value = round(100*nnz(app.filling_space)/numel(app.filling_space));
            
            % Total scan time and scan time / dynamic
            app.CalcTotalAcquisitionTime;
            
            % Set k-space dimensions and plot the k-space
            app.SetKspaceLimitsFcn;
            app.PlotKspaceDataFig;
            
            % Set buttons
            app.SetLights({'Green','Red','Red','Red','Red','Red'});
            app.SetButtons({'On','On','Off','Off','Off','Off','On'});
            app.SetStatus(0);
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;
            
        end

        % Value changed function: ZEditField
        function RecoSizeFieldValueChanged(app, event)
            
            app.stopmovie = true;
            drawnow;
            
            % Set buttons
            app.SetLights({'Null','Red','Red','Red','Red','Red'});
            app.SetButtons({'On','On','Off','Off','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;
            
        end

        % Value changed function: CSRecoCheckBox
        function CSRecoCheckBoxValueChanged(app, event)
            
            app.SetRecoFields;
            
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            
            if app.valid_file
                
                % Set buttons
                app.SwitchOffAllButtons;
                app.SetLights({'Null','Yellow','Null','Null','Null','Null'});
                app.DisableCoilButtons;
                
                % Indicate busy
                set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
                app.SetStatus(3);
                
                % For testing purposes
                % app.bart_detected = false;
                
                
                % Reset some buttons, stop the movies
                app.stop_reco = false;
                app.valid_reco = false;
                app.validNIFTIdata = false;
                app.valid_movie = false;
                app.stopmovie = true;
                app.kstopmovie = true;
                app.newfigflag = true;
                app.AbortButton.Enable = 'on';
                app.RecoProgressGauge.Value = 0;
                drawnow;
                
                app.TextMessage('Reconstructing the data ...');
                
                % Images = (X, Y, Z, NR, NFA, NE)
                app.images = [];
                averages = [];
                recokspace = {};
                
                % Intitate progress gauage
                progress_cnt = 0;
                totalcounter = app.NFAViewField.Value*app.NEViewField.Value;
                
                % Loop over flip-angles
                fa_cnt = 0;
                while fa_cnt<app.NFAViewField.Value && ~app.stop_reco
                    
                    fa_cnt = fa_cnt + 1;
                    
                    cine = false;
                    if isfield(app.seqpar,'frame_loop_on')
                        if (app.seqpar.frame_loop_on == 1) && app.bart_detected && app.CSRecoCheckBox.Value == 1
                            cine = true;
                        end
                    end
                    
                    if cine
                        
                        % CINE data, for which TE-dimension is the CINE loop
                        switch app.data_type
                            
                            case "2D"
                                
                                app.TextMessage('2D CINE compressed-sensing reconstruction ...');
                                
                                % Prepare 2D input
                                for i=1:app.ncoils
                                    %                             X Y slices NR flip-angles echo-times
                                    recokspace{i} = app.kspace{i}(:,:,:,:,fa_cnt,:);
                                end
                                
                                try
                                    app.images(:,:,:,:,fa_cnt,:) = cs_reco2D_mc_cine(app,recokspace,app.ncoils,app.AutoSensitivityCheckBox.Value,app.coilsensitivities,app.coilactive,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value,app.NREditField.Value);
                                    app.valid_reco = true;
                                catch ME
                                    app.TextMessage(ME.message);
                                    app.valid_reco = false;
                                end
                                
                                
                            case "3D"
                                
                                app.TextMessage('ERROR: Reconstruction of 3D CINE reco not implemented yet ...');
                                app.valid_reco = false;
                                
                                
                            case "2Dradial"
                                
                                app.TextMessage('ERROR: Reconstruction of 2D Radial CINE not implemented yet ...');
                                app.valid_reco = false;
                        end
                        
                        
                    else
                        % single- or multi-echo data
                        
                        % Loop over echo-times
                        te_cnt = 0;
                        while te_cnt<app.NEViewField.Value && ~app.stop_reco
                            
                            te_cnt = te_cnt + 1;
                            
                            switch app.data_type
                                
                                case "2D"
                                    
                                    % ------------------------------
                                    %
                                    % 2D cartesian reco
                                    %
                                    % ------------------------------
                                    
                                    % Report on progress
                                    app.TextMessage(strcat('Reconstructing flip-angle #',num2str(fa_cnt),', echo #',num2str(te_cnt),' ...'));
                                    app.RecoProgressGauge.Value = round(100*progress_cnt/totalcounter);
                                    progress_cnt = progress_cnt + 1;
                                    drawnow;
                                    
                                    % Prepare 2D input
                                    for i=1:app.ncoils
                                        %                             X Y slices NR flip-angles echo-times
                                        recokspace{i} = app.kspace{i}(:,:,:,:,fa_cnt,te_cnt);
                                    end
                                    averages = app.filling_space(:,:,:,:,fa_cnt,te_cnt);
                                    
                                    if app.CSRecoCheckBox.Value == 1
                                        
                                        % CS reconstruction
                                        
                                        
                                        if app.bart_detected
                                            try
                                                app.images(:,:,:,:,fa_cnt,te_cnt) = cs_reco2D_mc(app,recokspace,app.ncoils,app.AutoSensitivityCheckBox.Value,app.coilsensitivities,app.coilactive,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value,app.NREditField.Value);
                                                app.valid_reco = true;
                                            catch ME
                                                app.TextMessage(ME.message);
                                                app.valid_reco = false;
                                            end
                                        else
                                            try
                                                app.TextMessage('WARNING: Bart toolbox not available, slow reconstruction ...');
                                                app.SetStatus(1);
                                                pause(2.0);
                                                app.SetStatus(3);
                                                app.NREditField.Value = size(app.kspace{1},4);
                                                app.images(:,:,:,:,fa_cnt,te_cnt) = cs_reco2D_mat_mc(app,recokspace,averages,app.ncoils,app.AutoSensitivityCheckBox.Value,app.coilsensitivities,app.coilactive,app.WVxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value);
                                                app.valid_reco = true;
                                                if size(app.images,3)>1 app.validNIFTIdata = true; else app.validNIFTIdata = false; end
                                            catch ME
                                                app.TextMessage(ME.message);
                                                app.valid_reco = false;
                                                app.validNIFTIdata = false;
                                            end
                                        end
                                        
                                    else
                                        
                                        % Normal FFT reconstruction
                                        
                                        try
                                            app.NREditField.Value = size(recokspace{1},4);
                                            app.images(:,:,:,:,fa_cnt,te_cnt) = fft_reco2D_mc(app,recokspace,app.ncoils,app.AutoSensitivityCheckBox.Value,app.coilsensitivities,app.coilactive,app.XEditField.Value,app.YEditField.Value);
                                            app.valid_reco = true;
                                            if size(app.images,3)>1 app.validNIFTIdata = true; else app.validNIFTIdata = false; end
                                        catch ME
                                            app.TextMessage(ME.message);
                                            app.valid_reco = false;
                                            app.validNIFTIdata = false;
                                        end
                                        
                                    end
                                    
                                    
                                case "3D"
                                    
                                    % ------------------------------
                                    %
                                    % 3D cartesian reco
                                    %
                                    % ------------------------------
                                    
                                    % Report on progress
                                    app.TextMessage(strcat('Reconstructing flip-angle #',num2str(fa_cnt),', echo #',num2str(te_cnt),' ...'));
                                    app.RecoProgressGauge.Value = round(100*progress_cnt/totalcounter);
                                    progress_cnt = progress_cnt + 1;
                                    drawnow;
                                    
                                    % Prepare 3D input
                                    for i=1:app.ncoils
                                        %                             X Y Z NR
                                        recokspace{i} = app.kspace{i}(:,:,:,:,fa_cnt,te_cnt);
                                    end
                                    averages = app.filling_space(:,:,:,:,fa_cnt,te_cnt);
                                    
                                    if app.CSRecoCheckBox.Value == 1
                                        
                                        % 3D CS reconstruction


                                        if app.bart_detected
                                            
                                            try
                                                app.images(:,:,:,:,fa_cnt,te_cnt) = cs_reco3D_mc(app,recokspace,app.ncoils,app.AutoSensitivityCheckBox.Value,app.coilsensitivities,app.coilactive,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.LRxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value,app.NREditField.Value);
                                                app.valid_reco = true;
                                                app.validNIFTIdata = true;
                                            catch ME
                                                app.TextMessage(ME.message);
                                                app.valid_reco = false;
                                                app.validNIFTIdata = false;
                                            end
                                            
                                        else
                                            
                                            try
                                                app.TextMessage('WARNING: Bart toolbox not available, slow reconstruction ...');
                                                app.SetStatus(1);
                                                pause(2.0);
                                                app.SetStatus(3);
                                                app.images(:,:,:,:,fa_cnt,te_cnt) = cs_reco3D_mat_mc(app,recokspace,averages,app.ncoils,app.AutoSensitivityCheckBox.Value,app.coilsensitivities,app.coilactive,app.WVxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value);
                                                app.valid_reco = true;
                                                app.validNIFTIdata = true;
                                            catch ME
                                                app.TextMessage(ME.message);
                                                app.valid_reco = false;
                                                app.validNIFTIdata = false;
                                            end
                                            
                                        end
                                        
                                    else
                                        
                                        % Normal 3D FFT reconstruction
                                        
                                        try
                                            app.NREditField.Value = size(recokspace{1},4);
                                            app.images(:,:,:,:,fa_cnt,te_cnt) = fft_reco3D_mc(app,recokspace,app.ncoils,app.AutoSensitivityCheckBox.Value,app.coilsensitivities,app.coilactive,app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value);
                                            app.valid_reco = true;
                                            app.validNIFTIdata = true;
                                        catch ME
                                            app.TextMessage(ME.message);
                                            app.valid_reco = false;
                                            app.validNIFTIdata = false;
                                        end
                                        
                                    end
                                    
                                    
                                case "2Dradial"
                                    
                                    % ------------------------------
                                    %
                                    % 2D radial reconstruction
                                    %
                                    % ------------------------------
                                    
                                    slice_cnt = 0;
                                    while slice_cnt<app.ZEditField.Value && ~app.stop_reco
                                        
                                        slice_cnt = slice_cnt + 1;
                                        
                                        % Report progress
                                        app.TextMessage(strcat('Reconstructing slice #',num2str(slice_cnt),', flip-angle #',num2str(fa_cnt),', echo #',num2str(te_cnt),' ...'));
                                        app.RecoProgressGauge.Value = round(100*progress_cnt/totalcounter);
                                        progress_cnt = progress_cnt + 1;
                                        drawnow;
                                        
                                        % Do the reco, x and y dimensions seem to be permuted after 2D reco
                                        for i=1:app.ncoils
                                            recokspace{i} = app.kspace{i}(:,:,slice_cnt,:,fa_cnt,te_cnt);
                                        end
                                        
                                        try
                                            app.images(:,:,slice_cnt,:,fa_cnt,te_cnt) = recoMRDradial_mc(app,recokspace,app.ncoils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value,1,app.NREditField.Value);
                                            app.valid_reco = true;
                                        catch ME
                                            app.TextMessage(ME.message);
                                            app.valid_reco = false;
                                        end
                                        
                                    end
                                    
                            end
                            
                        end
                        
                    end
                    
                    
                    app.RecoProgressGauge.Value = 100;
                    drawnow;
                    
                end
                
                % Readout oversampling
                if isfield(app.seqpar, 'oversample')
                    if app.seqpar.oversample == 1
                        oversample = round(app.XEditField.Value/4);
                        app.images = app.images(oversample+1:end-oversample,:,:,:,:,:);
                    end
                end
                
            end
            
            % Reset pointer
            set(app.P2ROUD_UIFigure, 'Pointer', 'arrow');
            
            if app.valid_reco
                
                % Show the images
                app.reco_matrix = size(app.images);
                app.images = round(4095*app.images/max(app.images(:)));
                app.images(isnan(app.images)) = 0;
                
                if size(app.images,4)>1
                    app.valid_movie = true;
                end
                app.SetImageLimitsFcn;
                app.SetIntensity;
                app.PlotImageDataFig;
                app.TextMessage(strcat('Size of image =',{' '},num2str(size(app.images))));
                if app.seqpar.PHASE_ORIENTATION
                    app.TextMessage('INFO: phase orientation = 1 / horizontal ... ')
                else
                    app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                end
                
                if app.stop_reco
                    app.SetStatus(1);
                    app.SetLights({'Null',rgb(Color,'orange'),'Red','Red','Red'});
                    app.TextMessage('WARNING: Reconstruction interrupted ...');
                else
                    app.SetStatus(0);
                    app.SetLights({'Null','Green','Red','Red','Red','Red'});
                end
                
                % Set buttons
                app.SetButtons({'On','On','On','On','On','On','On'});
                app.EnableCoilButtons;
                app.ExportProgressGauge.Value = 0;
                app.SetStatus(0);
                
            else
                
                % Set buttons
                app.SetButtons({'On','On','Off','Off','Off','Off','On'});
                app.SetLights({'Null','Red','Red','Red','Red','Red'});
                app.EnableCoilButtons;
                app.TextMessage('ERROR: Something went wrong');
                app.SetStatus(2);
                
            end
            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            
            app.stop_reco = true;
            app.AbortButton.Enable = 'off';
            app.TextMessage('Reco will be aborted ...');
            app.TextMessage('Please wait ...');
            drawnow;
        
        end

        % Value changed function: Dim3EditField
        function Dim3EditFieldValueChanged(app, event)
            
            app.Dim3Slider.Value = app.Dim3EditField.Value;
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim3Slider
        function Dim3SliderValueChanged(app, event)
            
            app.Dim3EditField.Value = round(app.Dim3Slider.Value);
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim4EditField
        function Dim4EditFieldValueChanged(app, event)
            
            app.Dim4Slider.Value = app.Dim4EditField.Value;
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim4Slider
        function Dim4SliderValueChanged(app, event)
            
            app.Dim4EditField.Value = round(app.Dim4Slider.Value);
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim5EditField
        function Dim5EditFieldValueChanged(app, event)
          
            app.Dim5Slider.Value = app.Dim5EditField.Value;
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim5Slider
        function Dim5SliderValueChanged(app, event)
            
            app.Dim5EditField.Value = round(app.Dim5Slider.Value);
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim6EditField
        function Dim6EditFieldValueChanged(app, event)
            
            app.Dim6Slider.Value = app.Dim6EditField.Value;
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim6Slider
        function Dim6SliderValueChanged(app, event)
            
            app.Dim6EditField.Value = round(app.Dim6Slider.Value);
            app.PlotImageDataFig;
            
        end

        % Value changed function: OrientationSpinner
        function OrientationSpinnerValueChanged(app, event)
            
            app.SetImageLimitsFcn;
            app.newfigflag = true;
            app.PlotImageDataFig;
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)
            
            if app.valid_reco
                
                app.AutoIntensityCheckBox.Value = 0;
                app.LevelEditField.Value = round(app.LevelSlider.Value);
                app.level = app.LevelEditField.Value;
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            
            if app.valid_reco
                
                app.AutoIntensityCheckBox.Value = 0;
                app.LevelSlider.Value = app.LevelEditField.Value;
                app.level = app.LevelEditField.Value;
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)
            
            if app.valid_reco
                
                app.AutoIntensityCheckBox.Value = 0;
                app.WindowEditField.Value = round(app.WindowSlider.Value);
                app.window = app.WindowEditField.Value;
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
            
            if app.valid_reco
                
                app.AutoIntensityCheckBox.Value = 0;
                app.WindowSlider.Value = app.WindowEditField.Value;
                app.window = app.WindowEditField.Value;
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: AutoIntensityCheckBox
        function AutoIntensityCheckBoxValueChanged(app, event)
            
            if app.AutoIntensityCheckBox.Value == 1
                app.PlotImageDataFig;
            end
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            
            if app.valid_reco
                
                app.AutoIntensityCheckBox.Value = 0;
                app.SetIntensity;
                app.PlotImageDataFig;
                
            end
        
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if app.valid_reco
                
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                app.ExitButton.Enable = 'off';
                app.stopmovie = false;
                
                app.TextMessage('Starting the movie ...');
                
                counter = 1;
                while ~app.stopmovie
                    
                    app.Dim4EditField.Value = counter;
                    app.Dim4Slider.Value = counter;
                    app.PlotImageDataFig;
                    
                    counter = counter + 1;
                    im = permute(abs(app.images),app.image_orient(app.OrientationSpinner.Value,:));
                    if counter>size(im,4)
                        counter = 1;
                    end
                    
                end
                
                app.StartButton.Enable = 'on';
                app.StopButton.Enable = 'off';
                
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            app.TextMessage('Stopping the movie ...');
            
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopmovie = true;
            app.ExitButton.Enable = 'on';
            drawnow;
            
        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)
            
            % Export DICOM
            
            if app.valid_reco
                
                % Set buttons
                app.SwitchOffAllButtons;
                app.SetLights({'Null','Null','Yellow','Null','Null','Null'});
                set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
                app.SetStatus(3);
                
                % Check whether dicom-path is defined in dcmpath.txt file, only 1x per session
                if isfile('dcmpath.txt') && app.dicom_flag
                    folder_name = fileread('dcmpath.txt');
                    if exist(folder_name, 'dir')
                        app.dicom_export_path = folder_name;
                        app.dicom_flag = false;
                    end
                end
                
                % search for dicom file
                app.TextMessage('Searching for DICOM information ...');
                
                % base / data / 343 / DICOM / 8455 /
                dcm_spath{1} = replace(app.mrd_import_path,'MRD','Data');
                dcmpos = strfind(dcm_spath{1},filesep);
                dcmpos = dcmpos(end-1);
                dcm_spath{1} = insertBefore(dcm_spath{1},dcmpos,[filesep,'DICOM']);
                
                % base / data / 343 / DICOM / 8455 / 1 /
                dcm_spath{2} = [dcm_spath{1},'1',filesep];
                
                % base / data / 343 / DICOM / 8455 / 2 /
                dcm_spath{3} = [dcm_spath{1},'2',filesep];
                
                % Check if one of the directories contains dicom files
                dcm_exist = false;
                app.TextMessage('Searching for DICOM info in the following locations ...');
                for i=1:length(dcm_spath)
                    app.TextMessage(dcm_spath{i});
                    if ~isempty(dir([dcm_spath{i},filesep,'*.dcm']))
                        dcm_path = dcm_spath{i};
                        dcm_exist = true;
                        app.TextMessage('Found DICOM info ...');
                        filesepIdx = strfind(dcm_path, filesep);
                        app.dicom_export_path = dcm_path(filesepIdx(1):filesepIdx(end-4));
                        break;
                    end
                end
                
                % Ask for directory
                try
                    app.P2ROUD_UIFigure.Visible = 'off';
                    app.dicom_export_path = uigetdir(app.dicom_export_path,'DICOM export path');
                    app.P2ROUD_UIFigure.Visible = 'on';
                catch ME
                    app.TextMessage(ME.message);
                end
                
                % Indicate busy
                set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
                app.SetStatus(3);
                
                % Some parameters
                switch app.data_type
                    case "3D"
                        app.seqpar.dimensionality = '3D';
                        app.seqpar.SLICE_SEPARATION = app.FOVViewField3.Value/size(app.images,3);
                        app.seqpar.SLICE_THICKNESS = app.FOVViewField3.Value/size(app.images,3);
                        
                    case {"2D","2Dradial"}
                        app.seqpar.dimensionality = '2D';
                        app.seqpar.SLICE_SEPARATION = app.seqpar.SLICE_SEPARATION/app.seqpar.SLICE_INTERLEAVE;
                        app.seqpar.SLICE_THICKNESS = app.FOVViewField3.Value;
                end
                app.seqpar.SLICE_INTERLEAVE = 1;
                app.seqpar.FOV = app.FOVViewField1.Value;
                app.seqpar.NO_SLICES = size(app.images,3);
                app.seqpar.NO_VIEWS = size(app.images,2);
                app.seqpar.NO_VIEWS2 = size(app.images,3);
                app.seqpar.field_strength = 7;
                app.seqpar.filename = 111;
                app.CalcTotalAcquisitionTime;
                app.seqpar.acqdur = app.totalacquisitiontime;
                app.seqpar.timeperframe = app.totalacquisitiontime/app.NREditField.Value;
                app.seqpar.nr_frames = app.NREditField.Value;
               
                % Dicom export
                if dcm_exist
                    
                    % Export image with Dicom information available
                    app.TextMessage('Dicom export ...');
                    
                    try
                        success = true;
                        exportpath = export_dicom_DCM(app,dcm_path,app.dicom_export_path,app.images,app.seqpar);
                        app.ExportRecoParametersFcn(exportpath);
                    catch ME
                        app.TextMessage(ME.message);
                        success = false;
                    end
                    
                else
                    
                    % Export without image orientation information
                    app.TextMessage('WARNING: DICOM image orientation information not available ...');
                    app.SetStatus(1);
                    
                    try
                        success = true;
                        exportpath = export_dicom_MRD(app,app.dicom_export_path,app.images,app.seqpar,app.tag);
                        app.ExportRecoParametersFcn(exportpath);
                    catch ME
                        app.TextMessage(ME.message);
                        success = false;
                    end
                    
                end
                
                % Successfull export true/false
                if success
                    
                    app.TextMessage('DICOM export finished ...');
                    app.SetStatus(0);
                    app.SetLights({'Null','Null','Green','Null','Null','Null'});
                    SetButtons(app,{'On','On','On','On','On','On','On'});
                    
                else
                    
                    app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                    app.SetStatus(2);
                    app.SetLights({'Null','Null','Red','Null','Null','Null'});
                    SetButtons(app,{'On','On','On','On','On','On','On'});
                    
                end
                
                % Reset pointer
                set(app.P2ROUD_UIFigure, 'Pointer', 'arrow');
                
                
            else
                
                app.TextMessage('ERROR: No suitable data for export available ...');
                app.SetStatus(2);
                
            end
            
        end

        % Button pushed function: ExportNIFTIButton
        function ExportNIFTIButtonPushed(app, event)
            
            
            if app.valid_reco && app.validNIFTIdata
                
                % Set buttons
                app.SwitchOffAllButtons;
                app.SetLights({'Null','Null','Null','Yellow','Null','Null'});
                set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
                app.SetStatus(3);
                
                % Ask for directory
                app.P2ROUD_UIFigure.Visible = 'off';
                app.nifti_export_path = uigetdir(app.nifti_export_path,'NIFTI export path');
                app.P2ROUD_UIFigure.Visible = 'on';
                
                % Indicate busy
                set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
                app.SetStatus(3);
                app.TextMessage('Exporting to NIFTI ...');
                
                % Normalize images and determine dimensions
                image4D = squeeze(app.images(:,:,:,:,1,1));
                im = round(32767 * image4D / max(image4D(:)));
                im = cast(im,'single');
                
                % Phase orientation  --- only in the last step, I think
                if isfield(app.seqpar, 'PHASE_ORIENTATION')
                    if app.seqpar.PHASE_ORIENTATION == 1
                        app.TextMessage('INFO: phase orientation = 1');
                        im = permute(rot90(permute(im,[2 1 3 4 5 6]),1),[2 1 3 4 5 6]);
                        FOVx = app.FOVViewField2.Value;
                        FOVy = app.FOVViewField1.Value;
                    else
                        FOVx = app.FOVViewField1.Value;
                        FOVy = app.FOVViewField2.Value;
                    end
                end
                
                % Of course orientations are never correct and something needs to be flipped
                im = flip(im,1);
                [dimx,dimy,dimz,nrframes] = size(im);
                
                % Set some parameters
                app.CalcTotalAcquisitionTime;
                app.seqpar.timeperframe = 0;
                if nrframes>1
                    app.seqpar.timeperframe = app.totalacquisitiontime/app.NREditField.Value;
                end
                load('niftiinfo.mat');
                info.Filename = 'image4D.nii';
                info.Filemoddate = datestr(datetime);
                info.Datatype = 'single';
                info.BitsPerPixel = 32;
                info.Filesize = dimx*dimy*dimz*nrframes*32;
                
                switch app.data_type
                    case "2D"
                        zdim = app.FOVViewField3.Value;
                    case "3D"
                        zdim = app.FOVViewField3.Value/dimz;
                end
                
                if nrframes>1
                    info.ImageSize = [dimx dimy dimz nrframes];
                    info.PixelDimensions = [FOVx/dimx FOVy/dimy zdim app.seqpar.timeperframe];
                else
                    info.ImageSize = [dimx dimy dimz];
                    info.PixelDimensions = [FOVx/dimx FOVy/dimy zdim];
                end
                
                info.raw.dim = [3 dimx dimy dimz nrframes 1 1 1 1];
                info.raw.pixdim = [FOVx/dimx FOVy/dimy zdim app.seqpar.timeperframe 0 0 0 0];
                info.vox_offset = 0;
                
                % Export NIFTI
                try
                    niftiwrite(im,[app.nifti_export_path,'/image4D.nii'],info);
                    app.ExportRecoParametersFcn(app.nifti_export_path);
                    success = true;
                catch ME
                    app.TextMessage(ME.message);
                    success = false;
                end
                
                % Successfull export true/false
                if success
                    
                    app.TextMessage('NIFTI export finished ...');
                    app.SetStatus(0);
                    app.SetLights({'Null','Null','Null','Green','Null','Null'});
                    SetButtons(app,{'On','On','On','On','On','On','On'});
                    
                else
                    
                    app.TextMessage('ERROR: Something went wrong during NIFTI export ...');
                    app.SetStatus(2);
                    app.SetLights({'Null','Null','Null','Red','Null','Null'});
                    SetButtons(app,{'On','On','On','On','On','On','On'});
                    
                end
                
                % Reset pointer
                set(app.P2ROUD_UIFigure, 'Pointer', 'arrow');
                
            else
                
                app.TextMessage('ERROR: Data not suitable for NIFTI export ...');
                app.SetStatus(2);
                
            end
            
        end

        % Button pushed function: ExportMRDButton
        function ExportMRDButtonPushed(app, event)
            
            % MRD export
            
            if app.valid_reco
                
                % Set buttons
                app.SwitchOffAllButtons;
                app.SetLights({'Null','Null','Null','Null','Yellow','Null'});
                set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
                app.SetStatus(3);
                
                % Indicate busy
                app.TextMessage('MRD file export ...');
                
                % Make a k-space from the movie
                % Dimensions = (samples, views, views2, slices, echoes (frames), experiments, flip-angles)
                app.mrd_kspace = backtokspace(app.data_type,app.images);
                
                % Parameters for mrd footer
                
                [par.NoSamples,par.NoViews,par.NoViews2,par.NoSlices,par.NoEchoes,par.NoExperiments,par.NoFlipAngles] = size(app.mrd_kspace);
                if par.NoFlipAngles > 1
                    par.NoExperiments = par.NoExperiments * par.NoFlipAngles;
                end
                par.peorder = 0;
                par.slicenav = 0;
                par.navon = 0;
                par.radialon = 0;
                par.NoAverages = app.NAViewField.Value;
                par.batchslices = 1;
                par.frameloopon = 0;
                par.viewspersegment = 1;
                par.nodiscard = 0;
                par.View2order = "SEQUENTIAL";
                par.te = app.TEViewField.Value;
                if isfield(app.seqpar, 'frame_loop_on')
                    if app.seqpar.frame_loop_on == 1
                        par.frameloopon = 1;
                        par.te = 0;  % set to zero, because no_echoes dimension is used for (cardiac) frames
                    end
                end
                par.tr = app.TRViewField.Value;
                
                % Construct a new footer for the MRD file
                newmrdfooter = makemrdfooter(app.mrdfooter,par);
                
                % Construct a new rpr file if input rpr file was available
                if app.rpr_flag
                    newrpr = makerpr(app.rpr,par);
                end
                
                % First try auto export the new MRD and rpr files
                success = true;
                app.mrd_export_path = app.mrd_import_path;
                
                % Make a unique file name
                if contains(app.tag,'p2roud')
                    k = strfind(app.tag,'_');
                    app.tag = extractAfter(app.tag,k(2));
                end
                cnt = 1;
                while true
                    mrdfilename = ['p2roud_',num2str(cnt),'_',app.tag,'.MRD'];
                    rprfilename = ['p2roud_',num2str(cnt),'_',app.tag,'.rpr'];
                    mrdexportfilename = [app.mrd_export_path,mrdfilename];
                    rprexportfilename = [app.mrd_export_path,rprfilename];
                    if ~isfile(mrdexportfilename) 
                        break; 
                    end
                    cnt = cnt + 1;
                end
                
                % Try the export in the MRD input directory
                try
                    data2mrd(mrdexportfilename, app.mrd_kspace, par, newmrdfooter);
                    app.ExportRecoParametersFcn(app.mrd_export_path);
                    if app.rpr_flag
                        data2rpr(rprexportfilename, newrpr);
                    end
                    if (exist(strcat(app.mrd_import_path,filesep,'rtable.rtv'), 'file') == 2)
                        movefile(strcat(app.mrd_import_path,filesep,'rtable.rtv'),strcat(app.mrd_import_path,filesep,'origrtable.rtv.orig'));
                    end
                catch ME
                    app.TextMessage(ME.message);
                    success = false;
                end
                
                % If unsuccessful, try again using user-input directory
                if ~success
                    
                    % Ask for directory
                    app.RetrospectiveUIFigure.Visible = 'off';
                    app.mrd_export_path = uigetdir(app.mrd_export_path,'MRD export path');
                    app.RetrospectiveUIFigure.Visible = 'on';
                    drawnow;
                    
                    % Export new MRD and rpr files to user-input directory
                    success = true;
                    mrdexportfilename = [app.mrd_export_path,filesep,mrdfilename];
                    rprexportfilename = [app.mrd_export_path,filesep,rprfilename];
                    try
                        data2mrd(mrdexportfilename, app.mrd_kspace, par, newmrdfooter);
                        app.ExportRecoParametersFcn(app.mrd_export_path);
                        if app.rpr_flag
                            data2rpr(rprexportfilename, newrpr);
                        end
                        if (exist(strcat(app.mrd_import_path,filesep,'rtable.rtv'), 'file') == 2)
                            movefile(strcat(app.mrd_import_path,filesep,'rtable.rtv'),strcat(app.mrd_import_path,filesep,'origrtable.rtv.orig'));
                        end
                    catch ME
                        app.TextMessage(ME.message);
                        success = false;
                    end
                    
                end
                
                % Try to autoreconstruct and export of SUR files, only try when platform is pc
                if success && ispc
                    
                    % Determine SUR directory
                    try
                        scanpos = strfind(app.mrd_import_path,filesep);
                        scantag = strcat(app.mrd_import_path(scanpos(end-2)+1:scanpos(end-1)-1),filesep,'Image',filesep,app.mrd_import_path(scanpos(end-1)+1:scanpos(end)-1));
                        sur_path = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','BaseStorageDirectory');
                        sur_path1 = [sur_path,filesep,scantag,filesep,'1',filesep];
                        sur_path2 = [sur_path,filesep,scantag,filesep];
                        app.TextMessage('Data directory found in Windows registry ...');
                    catch ME
                        app.TextMessage(ME.message);
                        sur_path = replace(app.mrd_import_path,'MRD','Data');
                        surpos = strfind(sur_path,filesep);
                        surpos = surpos(end-1);
                        sur_path1 = [insertBefore(sur_path,surpos,[filesep,'Image']),'1',filesep];
                        sur_path2 = insertBefore(sur_path,surpos,[filesep,'Image']);
                    end
                    
                    sur_path = sur_path2;
                    if (exist(sur_path1, 'dir'))
                        sur_path = sur_path1;
                    end
                    
                    app.TextMessage(strcat('SUR directory =',{' '},sur_path));
                    
                    % SUR file names
                    sursuffix = ['p2roud_',app.tag];
                    
                    % Make and save the new SUR files
                    try
                        recosurfiles(sur_path,sursuffix,mrdexportfilename,rprexportfilename);
                    catch ME
                        app.TextMessage(ME.message);
                        app.TextMessage('WARNING: Automatic reconstruction of SUR files failed ...');
                        app.SetStatus(1);
                    end
                    
                end
                
                % Reset buttons
                if success
                    app.TextMessage('MRD export successfully completed ...');
                    app.SetLights({'Null','Null','Null','Null','Green','Null'});
                    SetButtons(app,{'On','On','On','On','On','On','On'});
                    app.SetStatus(0);
                else
                    app.TextMessage('ERROR: Something went wrong during MRD export ...');
                    app.SetStatus(2);
                    app.SetLights({'Null','Null','Null','Null','Red','Null'});
                    SetButtons(app,{'On','On','On','On','On','On','On'});
                end
                
                % Reset pointer
                set(app.P2ROUD_UIFigure, 'Pointer', 'arrow');
                
            else
                
                % Error
                app.TextMessage('ERROR: No suitable data for export available ...');
                app.SetStatus(2);
                
            end
            
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
            
            if app.valid_reco
                
                % Set buttons
                %app.SwitchOffAllButtons;
                app.SetLights({'Null','Null','Null','Null','Null','Yellow'});
                set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
                app.SetStatus(3);
                
                % Ask for directory
                app.P2ROUD_UIFigure.Visible = 'off';
                app.gif_export_path = uigetdir(app.gif_export_path,'GIF export path');
                app.P2ROUD_UIFigure.Visible = 'on';
                
                % Indicate busy
                set(app.P2ROUD_UIFigure, 'Pointer', 'watch');
                app.SetStatus(3);
                app.TextMessage('Exporting to GIF ...');
                
                % Export gif
                try
                    aspectratio = (app.FOVViewField1.Value/app.FOVViewField2.Value)*(app.XEditField.Value/app.YEditField.Value);
                    if app.seqpar.PHASE_ORIENTATION
                       aspectratio = 1/aspectratio; 
                    end
                    export_gif(app,app.gif_export_path,app.images,app.WindowEditField.Value,app.LevelEditField.Value,aspectratio);
                    app.ExportRecoParametersFcn(app.gif_export_path);
                    success = true;
                catch ME
                    app.TextMessage(ME.message);
                    success = false;
                end
                
                % Successfull export true/false
                if success
                    
                    app.TextMessage('GIF export finished ...');
                    app.SetStatus(0);
                    app.SetLights({'Null','Null','Null','Null','Null','Green'});
                    SetButtons(app,{'On','On','On','On','On','On','On'});
                    
                else
                    
                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
                    app.SetStatus(2);
                    app.SetLights({'Null','Null','Null','Null','Null','Red'});
                    SetButtons(app,{'On','On','On','On','On','On','On'});
                    
                end
                
                % Reset pointer
                set(app.P2ROUD_UIFigure, 'Pointer', 'arrow');
                
            else
                
                % Error
                app.TextMessage('ERROR: No data for export available ...');
                app.SetStatus(2);
                
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            app.P2ROUD_UIFigureCloseRequest;
            
        end

        % Close request function: P2ROUD_UIFigure
        function P2ROUD_UIFigureCloseRequest(app, event)
            
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('P2ROUD.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
            
        end

        % Value changed function: AutoSensitivityCheckBox
        function AutoSensitivityCheckBoxValueChanged(app, event)
            
            app.EnableCoilButtons;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.P2ROUD_UIFigure = uifigure('Visible', 'off');
            app.P2ROUD_UIFigure.CloseRequestFcn = createCallbackFcn(app, @P2ROUD_UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.P2ROUD_UIFigure);
            app.TitleLabel.Text = 'P2ROUD';
            app.TulpImage = uiimage(app.P2ROUD_UIFigure);
            app.CreditsLabel = uilabel(app.P2ROUD_UIFigure);
            app.CreditsLabel.Text = 'Gustav Strijkers, Bram Coolen, Amsterdam UMC, Version 1.4';
            app.MRSImage = uiimage(app.P2ROUD_UIFigure);
            app.P2ROUDLabel = uilabel(app.P2ROUD_UIFigure);
            app.P2ROUDLabel.Text = 'Preclinical PROspective Undersampling in multiple Dimensions';
            app.DashboardTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.DashboardTab = uitab(app.DashboardTabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDButtonPushed, true);
            app.LoadMRDButton.Enable = 'off';
            app.LoadMRDButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.ReconstructButton = uibutton(app.DashboardTab, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Enable = 'off';
            app.ReconstructButton.Text = 'Reconstruct';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Text = 'Export DCM';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '    Exit';
            app.RecodimensionsPanel = uipanel(app.DashboardTab);
            app.RecodimensionsPanel.Title = 'Reco dimensions';
            app.XEditFieldLabel = uilabel(app.RecodimensionsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.XEditField.Limits = [16 1024];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Value = 128;
            app.YEditFieldLabel = uilabel(app.RecodimensionsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.YEditField.Limits = [16 1024];
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Value = 128;
            app.ZEditFieldLabel = uilabel(app.RecodimensionsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.ZEditField.Limits = [1 1024];
            app.ZEditField.RoundFractionalValues = 'on';
            app.ZEditField.ValueDisplayFormat = '%.0f';
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @RecoSizeFieldValueChanged, true);
            app.ZEditField.Value = 128;
            app.NREditFieldLabel_2 = uilabel(app.RecodimensionsPanel);
            app.NREditFieldLabel_2.Text = 'NR';
            app.NREditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.NREditField.Limits = [1 1024];
            app.NREditField.RoundFractionalValues = 'on';
            app.NREditField.ValueDisplayFormat = '%.0f';
            app.NREditField.ValueChangedFcn = createCallbackFcn(app, @NREditFieldValueChanged, true);
            app.NREditField.Value = 1;
            app.RecoregularizationPanel = uipanel(app.DashboardTab);
            app.RecoregularizationPanel.Title = 'Reco regularization';
            app.TVxyzEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 2];
            app.TVxyzEditField.ValueDisplayFormat = '%.3f';
            app.TVxyzEditField.Tooltip = {'Total Varation in spatial domain'};
            app.TVxyzEditField.Value = 0.001;
            app.TVtimeEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.TVtimeEditFieldLabel.Text = 'TVtime';
            app.TVtimeEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.TVtimeEditField.Limits = [0 2];
            app.TVtimeEditField.ValueDisplayFormat = '%.3f';
            app.TVtimeEditField.Tooltip = {'Total Variation in time domain'};
            app.TVtimeEditField.Value = 0.001;
            app.WVxyzEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 2];
            app.WVxyzEditField.ValueDisplayFormat = '%.3f';
            app.WVxyzEditField.Tooltip = {'L1 Wavelet in spatial domain '};
            app.WVxyzEditField.Value = 0.001;
            app.CSRecoCheckBox = uicheckbox(app.RecoregularizationPanel);
            app.CSRecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CSRecoCheckBoxValueChanged, true);
            app.CSRecoCheckBox.Text = '';
            app.CSRecoLabel = uilabel(app.RecoregularizationPanel);
            app.CSRecoLabel.Text = 'CS reco';
            app.LRxyzEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.LRxyzEditFieldLabel.Text = 'LRxyz';
            app.LRxyzEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.LRxyzEditField.Limits = [0 2];
            app.LRxyzEditField.ValueDisplayFormat = '%.3f';
            app.LRxyzEditField.Tooltip = {'Low-rank in spatial domain'};
            app.LRxyzEditField.Value = 0.001;
            app.AbortButton = uibutton(app.DashboardTab, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Enable = 'off';
            app.AbortButton.Text = 'abort';
            app.RecoProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.ExportProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.ExportNIFTIButton = uibutton(app.DashboardTab, 'push');
            app.ExportNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNIFTIButtonPushed, true);
            app.ExportNIFTIButton.Enable = 'off';
            app.ExportNIFTIButton.Text = 'Export NIFTI';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.ExportMRDButton = uibutton(app.DashboardTab, 'push');
            app.ExportMRDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMRDButtonPushed, true);
            app.ExportMRDButton.Enable = 'off';
            app.ExportMRDButton.Text = 'Export MRD';
            app.Lamp5 = uilamp(app.DashboardTab);
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Text = 'Export GIF';
            app.Lamp6 = uilamp(app.DashboardTab);
            app.KspaceTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.KspaceTab = uitab(app.KspaceTabGroup);
            app.KspaceTab.Title = 'K-space';
            app.KspaceFig = uiaxes(app.KspaceTab);
            app.KspaceFig.XTick = [];
            app.KspaceFig.YTick = [];
            app.KDim3EditField = uieditfield(app.KspaceTab, 'numeric');
            app.KDim3EditField.Limits = [1 Inf];
            app.KDim3EditField.ValueDisplayFormat = '%.0f';
            app.KDim3EditField.ValueChangedFcn = createCallbackFcn(app, @KDim3EditFieldValueChanged, true);
            app.KDim3EditField.Value = 1;
            app.KDim3Slider = uislider(app.KspaceTab);
            app.KDim3Slider.Limits = [1 200];
            app.KDim3Slider.MajorTicks = [];
            app.KDim3Slider.ValueChangedFcn = createCallbackFcn(app, @KDim3SliderValueChanged, true);
            app.KDim3Slider.MinorTicks = [];
            app.KDim3Slider.Value = 1;
            app.KDim4Slider = uislider(app.KspaceTab);
            app.KDim4Slider.Limits = [1 200];
            app.KDim4Slider.MajorTicks = [];
            app.KDim4Slider.ValueChangedFcn = createCallbackFcn(app, @KDim4SliderValueChanged, true);
            app.KDim4Slider.MinorTicks = [];
            app.KDim4Slider.Value = 1;
            app.KDim4EditField = uieditfield(app.KspaceTab, 'numeric');
            app.KDim4EditField.Limits = [1 Inf];
            app.KDim4EditField.ValueDisplayFormat = '%.0f';
            app.KDim4EditField.ValueChangedFcn = createCallbackFcn(app, @KDim4EditFieldValueChanged, true);
            app.KDim4EditField.Value = 1;
            app.KxLabel = uilabel(app.KspaceTab);
            app.KxLabel.Text = '     Kx     ';
            app.KyLabel = uilabel(app.KspaceTab);
            app.KyLabel.Text = '     Ky     ';
            app.ButtonGroup = uibuttongroup(app.KspaceTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.fillingButton = uiradiobutton(app.ButtonGroup);
            app.fillingButton.Text = 'filling';
            app.averagesButton = uiradiobutton(app.ButtonGroup);
            app.averagesButton.Text = 'averages';
            app.kvaluesButton = uiradiobutton(app.ButtonGroup);
            app.kvaluesButton.Text = '| k-values |';
            app.kvaluesButton.Value = true;
            app.KDim3Label = uilabel(app.KspaceTab);
            app.KDim3Label.Text = '            Kz';
            app.KEchoSlider = uislider(app.KspaceTab);
            app.KEchoSlider.Limits = [1 200];
            app.KEchoSlider.MajorTicks = [];
            app.KEchoSlider.ValueChangedFcn = createCallbackFcn(app, @KEchoSliderValueChanged, true);
            app.KEchoSlider.MinorTicks = [];
            app.KEchoSlider.Value = 1;
            app.KDim4Label = uilabel(app.KspaceTab);
            app.KDim4Label.Text = '             NR';
            app.KFlipAngleSlider = uislider(app.KspaceTab);
            app.KFlipAngleSlider.Limits = [1 200];
            app.KFlipAngleSlider.MajorTicks = [];
            app.KFlipAngleSlider.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleSliderValueChanged, true);
            app.KFlipAngleSlider.MinorTicks = [];
            app.KFlipAngleSlider.Value = 1;
            app.FillingViewField = uieditfield(app.KspaceTab, 'numeric');
            app.FillingViewField.Editable = 'off';
            app.FillingPercLabel = uilabel(app.KspaceTab);
            app.FillingPercLabel.Text = '%';
            app.KStartButton = uibutton(app.KspaceTab, 'push');
            app.KStartButton.ButtonPushedFcn = createCallbackFcn(app, @KStartButtonPushed, true);
            app.KStartButton.Text = 'Start';
            app.KStopButton = uibutton(app.KspaceTab, 'push');
            app.KStopButton.ButtonPushedFcn = createCallbackFcn(app, @KStopButtonPushed, true);
            app.KStopButton.Text = 'Stop';
            app.KspacemovieLabel = uilabel(app.KspaceTab);
            app.KspacemovieLabel.Text = {'K-space'; 'movie'};
            app.FastSlowSwitch = uiswitch(app.KspaceTab, 'rocker');
            app.FastSlowSwitch.Items = {'fast', 'slow'};
            app.FastSlowSwitch.ValueChangedFcn = createCallbackFcn(app, @FastSlowSwitchValueChanged, true);
            app.FastSlowSwitch.Value = 'fast';
            app.KLevelSlider = uislider(app.KspaceTab);
            app.KLevelSlider.Limits = [0 32767];
            app.KLevelSlider.MajorTicks = [];
            app.KLevelSlider.ValueChangedFcn = createCallbackFcn(app, @KLevelSliderValueChanged, true);
            app.KLevelSlider.MinorTicks = [];
            app.KWLabel = uilabel(app.KspaceTab);
            app.KWLabel.Text = 'W';
            app.KLLabel = uilabel(app.KspaceTab);
            app.KLLabel.Text = 'L';
            app.KWindowSlider = uislider(app.KspaceTab);
            app.KWindowSlider.Limits = [0 32767];
            app.KWindowSlider.MajorTicks = [];
            app.KWindowSlider.ValueChangedFcn = createCallbackFcn(app, @KWindowSliderValueChanged, true);
            app.KWindowSlider.MinorTicks = [];
            app.KLevelEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KLevelEditField.Limits = [0 32767];
            app.KLevelEditField.ValueDisplayFormat = '%.0f';
            app.KLevelEditField.ValueChangedFcn = createCallbackFcn(app, @KLevelEditFieldValueChanged, true);
            app.KWindowEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KWindowEditField.Limits = [0 32767];
            app.KWindowEditField.ValueDisplayFormat = '%.0f';
            app.KWindowEditField.ValueChangedFcn = createCallbackFcn(app, @KWindowEditFieldValueChanged, true);
            app.EchoLabel = uilabel(app.KspaceTab);
            app.EchoLabel.Text = 'Echo';
            app.KEchoEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KEchoEditField.Limits = [1 Inf];
            app.KEchoEditField.ValueDisplayFormat = '%.0f';
            app.KEchoEditField.ValueChangedFcn = createCallbackFcn(app, @KEchoEditFieldValueChanged, true);
            app.KEchoEditField.Value = 1;
            app.OrientationSpinnerLabel_3 = uilabel(app.KspaceTab);
            app.OrientationSpinnerLabel_3.Text = 'Orientation';
            app.KOrientationSpinner = uispinner(app.KspaceTab);
            app.KOrientationSpinner.Limits = [1 6];
            app.KOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @KOrientationSpinnerValueChanged, true);
            app.KOrientationSpinner.Value = 1;
            app.FALabel = uilabel(app.KspaceTab);
            app.FALabel.Text = 'FA';
            app.KFlipAngleEditField = uieditfield(app.KspaceTab, 'numeric');
            app.KFlipAngleEditField.Limits = [1 Inf];
            app.KFlipAngleEditField.ValueDisplayFormat = '%.0f';
            app.KFlipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleEditFieldValueChanged, true);
            app.KFlipAngleEditField.Value = 1;
            app.CoilSpinnerLabel = uilabel(app.KspaceTab);
            app.CoilSpinnerLabel.Text = 'Coil';
            app.CoilSpinner = uispinner(app.KspaceTab);
            app.CoilSpinner.Limits = [1 16];
            app.CoilSpinner.ValueDisplayFormat = '%.0f';
            app.CoilSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilSpinnerValueChanged, true);
            app.CoilSpinner.Value = 1;
            app.CoilsTab = uitab(app.KspaceTabGroup);
            app.CoilsTab.Title = 'Coils';
            app.CoilselectionPanel = uipanel(app.CoilsTab);
            app.CoilselectionPanel.Title = 'Coil selection';
            app.C1CheckBox = uicheckbox(app.CoilselectionPanel);
            app.C1CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C1CheckBox.Text = ' 1';
            app.C1CheckBox.Value = true;
            app.C2CheckBox = uicheckbox(app.CoilselectionPanel);
            app.C2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C2CheckBox.Text = ' 2';
            app.C3CheckBox = uicheckbox(app.CoilselectionPanel);
            app.C3CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C3CheckBox.Text = ' 3';
            app.C4CheckBox = uicheckbox(app.CoilselectionPanel);
            app.C4CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C4CheckBox.Text = ' 4';
            app.C5CheckBox = uicheckbox(app.CoilselectionPanel);
            app.C5CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C5CheckBox.Text = ' 5';
            app.C6CheckBox = uicheckbox(app.CoilselectionPanel);
            app.C6CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C6CheckBox.Text = ' 6';
            app.C7CheckBox = uicheckbox(app.CoilselectionPanel);
            app.C7CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C7CheckBox.Text = ' 7';
            app.C8CheckBox = uicheckbox(app.CoilselectionPanel);
            app.C8CheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilCheckBoxValueChanged, true);
            app.C8CheckBox.Text = ' 8';
            app.S1EditField = uieditfield(app.CoilselectionPanel, 'numeric');
            app.S1EditField.Limits = [0 10];
            app.S1EditField.ValueDisplayFormat = '%.3f';
            app.S1EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S1EditField.Value = 1;
            app.CoilLabel = uilabel(app.CoilselectionPanel);
            app.CoilLabel.Text = 'Coil';
            app.S2EditField = uieditfield(app.CoilselectionPanel, 'numeric');
            app.S2EditField.Limits = [0 10];
            app.S2EditField.ValueDisplayFormat = '%.3f';
            app.S2EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S2EditField.Value = 1;
            app.RelativesensitivityLabel = uilabel(app.CoilselectionPanel);
            app.RelativesensitivityLabel.Text = 'Relative sensitivity';
            app.S3EditField = uieditfield(app.CoilselectionPanel, 'numeric');
            app.S3EditField.Limits = [0 10];
            app.S3EditField.ValueDisplayFormat = '%.3f';
            app.S3EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S3EditField.Value = 1;
            app.S4EditField = uieditfield(app.CoilselectionPanel, 'numeric');
            app.S4EditField.Limits = [0 10];
            app.S4EditField.ValueDisplayFormat = '%.3f';
            app.S4EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S4EditField.Value = 1;
            app.S5EditField = uieditfield(app.CoilselectionPanel, 'numeric');
            app.S5EditField.Limits = [0 10];
            app.S5EditField.ValueDisplayFormat = '%.3f';
            app.S5EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S5EditField.Value = 1;
            app.S6EditField = uieditfield(app.CoilselectionPanel, 'numeric');
            app.S6EditField.Limits = [0 10];
            app.S6EditField.ValueDisplayFormat = '%.3f';
            app.S6EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S6EditField.Value = 1;
            app.S7EditField = uieditfield(app.CoilselectionPanel, 'numeric');
            app.S7EditField.Limits = [0 10];
            app.S7EditField.ValueDisplayFormat = '%.3f';
            app.S7EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S7EditField.Value = 1;
            app.S8EditField = uieditfield(app.CoilselectionPanel, 'numeric');
            app.S8EditField.Limits = [0 10];
            app.S8EditField.ValueDisplayFormat = '%.3f';
            app.S8EditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.S8EditField.Value = 1;
            app.AutoSensitivityCheckBox = uicheckbox(app.CoilselectionPanel);
            app.AutoSensitivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoSensitivityCheckBoxValueChanged, true);
            app.AutoSensitivityCheckBox.Text = ' Auto sensitivity';
            app.ScanParameterTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.ScanParametersTab = uitab(app.ScanParameterTabGroup);
            app.ScanParametersTab.Title = 'Scan parameters';
            app.KMatrixViewField2 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField2.Limits = [1 Inf];
            app.KMatrixViewField2.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField2.Editable = 'off';
            app.KMatrixViewField2.Value = 1;
            app.KMatrixViewField3 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField3.Limits = [1 Inf];
            app.KMatrixViewField3.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField3.Editable = 'off';
            app.KMatrixViewField3.Value = 1;
            app.FOVViewField2 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField2.Limits = [0 Inf];
            app.FOVViewField2.ValueDisplayFormat = '%.1f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField3 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField3.Limits = [0 Inf];
            app.FOVViewField3.ValueDisplayFormat = '%.1f';
            app.FOVViewField3.Editable = 'off';
            app.MXLabel = uilabel(app.ScanParametersTab);
            app.MXLabel.Text = 'Kx';
            app.MYLabel = uilabel(app.ScanParametersTab);
            app.MYLabel.Text = 'Ky';
            app.MZLabel = uilabel(app.ScanParametersTab);
            app.MZLabel.Text = 'Kz';
            app.NREditFieldLabel_3 = uilabel(app.ScanParametersTab);
            app.NREditFieldLabel_3.Text = 'NR';
            app.NRViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NRViewField.Editable = 'off';
            app.SequenceEditFieldLabel = uilabel(app.ScanParametersTab);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceViewField = uieditfield(app.ScanParametersTab, 'text');
            app.SequenceViewField.Editable = 'off';
            app.MatrixEditFieldLabel = uilabel(app.ScanParametersTab);
            app.MatrixEditFieldLabel.Text = 'Matrix';
            app.KMatrixViewField1 = uieditfield(app.ScanParametersTab, 'numeric');
            app.KMatrixViewField1.Limits = [1 Inf];
            app.KMatrixViewField1.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField1.Editable = 'off';
            app.KMatrixViewField1.Value = 1;
            app.FOVmmLabel = uilabel(app.ScanParametersTab);
            app.FOVmmLabel.Text = 'FOV (mm)';
            app.FOVViewField1 = uieditfield(app.ScanParametersTab, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.1f';
            app.FOVViewField1.Editable = 'off';
            app.TRmsLabel = uilabel(app.ScanParametersTab);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.TRViewField.Editable = 'off';
            app.TEmsLabel = uilabel(app.ScanParametersTab);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.TEViewField.Editable = 'off';
            app.NALabel = uilabel(app.ScanParametersTab);
            app.NALabel.Text = 'NA';
            app.NAViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NAViewField.Editable = 'off';
            app.NFALabel = uilabel(app.ScanParametersTab);
            app.NFALabel.Text = 'NFA';
            app.NFAViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NFAViewField.Editable = 'off';
            app.NEEditFieldLabel = uilabel(app.ScanParametersTab);
            app.NEEditFieldLabel.Text = 'NE';
            app.NEViewField = uieditfield(app.ScanParametersTab, 'numeric');
            app.NEViewField.Editable = 'off';
            app.MRDfileLabel = uilabel(app.ScanParametersTab);
            app.MRDfileLabel.Text = 'MRD file';
            app.MRDfileViewField = uieditfield(app.ScanParametersTab, 'text');
            app.MRDfileViewField.Editable = 'off';
            app.FAdegEditFieldLabel = uilabel(app.ScanParametersTab);
            app.FAdegEditFieldLabel.Text = 'FA (deg)';
            app.FAViewField = uieditfield(app.ScanParametersTab, 'text');
            app.FAViewField.Editable = 'off';
            app.TrajectoryLabel = uilabel(app.ScanParametersTab);
            app.TrajectoryLabel.Text = 'Trajectory';
            app.Trajectory = uieditfield(app.ScanParametersTab, 'text');
            app.Trajectory.Editable = 'off';
            app.ScantimeEditFieldLabel = uilabel(app.ScanParametersTab);
            app.ScantimeEditFieldLabel.Text = 'Scan time';
            app.ScanTimeViewField = uieditfield(app.ScanParametersTab, 'text');
            app.ScanTimeViewField.Editable = 'off';
            app.TimedynEditFieldLabel = uilabel(app.ScanParametersTab);
            app.TimedynEditFieldLabel.Text = 'Time / dyn';
            app.TimeDynViewField = uieditfield(app.ScanParametersTab, 'text');
            app.TimeDynViewField.Editable = 'off';
            app.ReconstructionTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.ReconstructionTab = uitab(app.ReconstructionTabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.RecoFig = uiaxes(app.ReconstructionTab);
            app.RecoFig.XTick = [];
            app.RecoFig.YTick = [];
            app.Dim3Slider = uislider(app.ReconstructionTab);
            app.Dim3Slider.Limits = [1 200];
            app.Dim3Slider.MajorTicks = [];
            app.Dim3Slider.ValueChangedFcn = createCallbackFcn(app, @Dim3SliderValueChanged, true);
            app.Dim3Slider.MinorTicks = [];
            app.Dim3Slider.Value = 1;
            app.Dim5Slider = uislider(app.ReconstructionTab);
            app.Dim5Slider.Limits = [1 200];
            app.Dim5Slider.MajorTicks = [];
            app.Dim5Slider.ValueChangedFcn = createCallbackFcn(app, @Dim5SliderValueChanged, true);
            app.Dim5Slider.MinorTicks = [];
            app.Dim5Slider.Value = 1;
            app.Dim4Slider = uislider(app.ReconstructionTab);
            app.Dim4Slider.Limits = [1 200];
            app.Dim4Slider.MajorTicks = [];
            app.Dim4Slider.ValueChangedFcn = createCallbackFcn(app, @Dim4SliderValueChanged, true);
            app.Dim4Slider.MinorTicks = [];
            app.Dim4Slider.Value = 1;
            app.Dim3EditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.Dim3EditField.Limits = [1 Inf];
            app.Dim3EditField.ValueDisplayFormat = '%.0f';
            app.Dim3EditField.ValueChangedFcn = createCallbackFcn(app, @Dim3EditFieldValueChanged, true);
            app.Dim3EditField.Value = 1;
            app.Dim5EditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.Dim5EditField.Limits = [1 Inf];
            app.Dim5EditField.ValueDisplayFormat = '%.0f';
            app.Dim5EditField.ValueChangedFcn = createCallbackFcn(app, @Dim5EditFieldValueChanged, true);
            app.Dim5EditField.Value = 1;
            app.Dim4EditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.Dim4EditField.Limits = [1 Inf];
            app.Dim4EditField.ValueDisplayFormat = '%.0f';
            app.Dim4EditField.ValueChangedFcn = createCallbackFcn(app, @Dim4EditFieldValueChanged, true);
            app.Dim4EditField.Value = 1;
            app.YLabel = uilabel(app.ReconstructionTab);
            app.YLabel.Text = '     Y     ';
            app.XLabel = uilabel(app.ReconstructionTab);
            app.XLabel.Text = '     X     ';
            app.Dim6EditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.Dim6EditField.Limits = [1 Inf];
            app.Dim6EditField.ValueDisplayFormat = '%.0f';
            app.Dim6EditField.ValueChangedFcn = createCallbackFcn(app, @Dim6EditFieldValueChanged, true);
            app.Dim6EditField.Value = 1;
            app.Dim6Slider = uislider(app.ReconstructionTab);
            app.Dim6Slider.Limits = [1 200];
            app.Dim6Slider.MajorTicks = [];
            app.Dim6Slider.ValueChangedFcn = createCallbackFcn(app, @Dim6SliderValueChanged, true);
            app.Dim6Slider.MinorTicks = [];
            app.Dim6Slider.Value = 1;
            app.Dim3Label = uilabel(app.ReconstructionTab);
            app.Dim3Label.Text = '              Z';
            app.Dim4Label = uilabel(app.ReconstructionTab);
            app.Dim4Label.Text = '             NR';
            app.LevelSlider = uislider(app.ReconstructionTab);
            app.LevelSlider.Limits = [0 4095];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [];
            app.WLabel = uilabel(app.ReconstructionTab);
            app.WLabel.Text = 'W';
            app.LLabel = uilabel(app.ReconstructionTab);
            app.LLabel.Text = 'L';
            app.WindowSlider = uislider(app.ReconstructionTab);
            app.WindowSlider.Limits = [0 4095];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [];
            app.LevelEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.LevelEditField.Limits = [0 4095];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.WindowEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.WindowEditField.Limits = [0 4095];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.SetButton = uibutton(app.ReconstructionTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.StartButton = uibutton(app.ReconstructionTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ReconstructionTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Dim5Label = uilabel(app.ReconstructionTab);
            app.Dim5Label.Text = 'FA';
            app.Dim6Label = uilabel(app.ReconstructionTab);
            app.Dim6Label.Text = 'Echo';
            app.AutoIntensityCheckBox = uicheckbox(app.ReconstructionTab);
            app.AutoIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoIntensityCheckBoxValueChanged, true);
            app.AutoIntensityCheckBox.Text = '';
            app.AutoIntensityCheckBox.Value = true;
            app.AutoLabel = uilabel(app.ReconstructionTab);
            app.AutoLabel.Text = 'Auto';
            app.MovieImage = uiimage(app.ReconstructionTab);
            app.MovieImage.Enable = 'off';
            app.MovieLabel = uilabel(app.ReconstructionTab);
            app.MovieLabel.Text = 'Movie';
            app.OrientationSpinnerLabel_4 = uilabel(app.ReconstructionTab);
            app.OrientationSpinnerLabel_4.Text = 'Orientation';
            app.OrientationSpinner = uispinner(app.ReconstructionTab);
            app.OrientationSpinner.Limits = [1 12];
            app.OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinnerValueChanged, true);
            app.OrientationSpinner.Value = 1;
            app.MessagesTabGroup = uitabgroup(app.P2ROUD_UIFigure);
            app.MessagesTab = uitab(app.MessagesTabGroup);
            app.MessagesTab.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesTab);
            app.StatusBox = uitextarea(app.MessagesTab);
            app.StatusImage = uiimage(app.MessagesTab);
            app.statusLabel = uilabel(app.MessagesTab);
            app.statusLabel.Text = 'status';
            % Show the figure after all components are created
            app.P2ROUD_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2ROUD

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.P2ROUD_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.P2ROUD_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.P2ROUD_UIFigure)
        end
    end
end